classdef dendrite_branch_decoder_v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        FileMenu                   matlab.ui.container.Menu
        OpenMenu                   matlab.ui.container.Menu
        OpenNewMenu                matlab.ui.container.Menu
        ReloadCurrentMenu          matlab.ui.container.Menu
        ExitMenu                   matlab.ui.container.Menu
        HelpMenu                   matlab.ui.container.Menu
        GridLayout                 matlab.ui.container.GridLayout
        StackIn                    matlab.ui.container.Panel
        InputImage                 matlab.ui.control.UIAxes
        SS_label                   matlab.ui.control.Label
        FrameSelectLabel           matlab.ui.control.Label
        FrameSelect_Slider         matlab.ui.control.Slider
        SS_DrawNewButton           matlab.ui.control.Button
        Proj_ButtonGroup           matlab.ui.container.ButtonGroup
        Proj_NoneButton            matlab.ui.control.RadioButton
        Proj_MaxButton             matlab.ui.control.RadioButton
        Proj_VarButton             matlab.ui.control.RadioButton
        Proj_AvgButton             matlab.ui.control.RadioButton
        WindowSizeframesLabel      matlab.ui.control.Label
        Proj_WindowSizeEditField   matlab.ui.control.NumericEditField
        SS_MarkCompleteButton      matlab.ui.control.Button
        SS_DeleteButton            matlab.ui.control.Button
        FrameSelect_Spinner        matlab.ui.control.Spinner
        MinEditFieldLabel          matlab.ui.control.Label
        ColormapMinEditField       matlab.ui.control.NumericEditField
        MaxEditFieldLabel          matlab.ui.control.Label
        ColormapMaxEditField       matlab.ui.control.NumericEditField
        ColormapEditLabel          matlab.ui.control.Label
        DrawnROIsLabel             matlab.ui.control.Label
        DrawnROIsSwitch            matlab.ui.control.Switch
        ROISelectDropDownLabel     matlab.ui.control.Label
        ROISelectDropDown          matlab.ui.control.DropDown
        YBranchPlots               matlab.ui.container.Panel
        YBranchThreshold_Plot      matlab.ui.control.UIAxes
        TimecoursesPanel           matlab.ui.container.Panel
        TC_Plot_Proximal           matlab.ui.control.UIAxes
        TC_SyncROIsButton          matlab.ui.control.Button
        TC_SaveAsButton            matlab.ui.control.Button
        TC_ExitButton              matlab.ui.control.Button
        TC_Plot_Distal1            matlab.ui.control.UIAxes
        TC_RefreshButton           matlab.ui.control.Button
        TC_Plot_Distal2            matlab.ui.control.UIAxes
        YBranchSegment_Plot        matlab.ui.control.UIAxes
        ThreshAndSeg_Label         matlab.ui.control.Label
        ROITypeButtonGroup         matlab.ui.container.ButtonGroup
        ROI_ContourButton          matlab.ui.control.RadioButton
        ROI_RectangleButton        matlab.ui.control.RadioButton
        ROI_SplineButton           matlab.ui.control.RadioButton
        SetThresholdSliderLabel    matlab.ui.control.Label
        Thresh_SetThresholdSlider  matlab.ui.control.Slider
        Thresh_DrawNewButton       matlab.ui.control.Button
        Thresh_SegmentButton       matlab.ui.control.Button
        ROIWeightpixelsonlyusedforrectsplineLabel  matlab.ui.control.Label
        BranchSelectDropDownLabel  matlab.ui.control.Label
        BranchSelectDropDown       matlab.ui.control.DropDown
        ROIWeightSpinner           matlab.ui.control.Spinner
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    % Creator-defined properties, shared variables
    properties (Access = private)
        
        % input image stack
        current_file        = [];           % Currently loaded file name
        img                 = [];           % image stack for <current_file>
        
        % handle collector for plots
        Handles             = [];           % structure containing all the plot handles
        
        % input stack: projection
        proj_type           = 'none';       % string type of projeciton to use for plotting input image; default = 'none'
        proj_window_size    = 100;          % window size used for plotting input image; default = 100
        substack            = [];           % currently selected substack (based on app.frame_start & app.proj_window_size)
        curr_proj           = [];           % currently plotted image projection (based on app.substack & app.proj_type)
        
        % input stack: slider
        frame_start         = 1;            % first frame to plot for input image; deafult = 1
        
        % input stack: colormap min/max
        cmap_min            = 0;            % minimum for colormap; default = 0
        cmap_max            = 5000;         % maximum for colormap; default = 5000
        
        % input stack: rois on input fov 
        fov_rois            = [];           % data structure that collects user drawn ROI first, then updates based on thresholding/segmentation; these get plotted on the input stack for user reference
        fov_rois_counter    = 0;            % counter that indicates the current ROI #
        new_draw_resolved   = [];           % logical indicator for whether currently drawn ROI has been 'marked complete', otherwise warns user before preceeding
        
        % dendrite y-branch data structure
        ybranch             = [];
        
        % y-branch threshold
        thresh_val          = [];           % threshold value from slider, default = 1
        thresh_lim          = [];           % threshold limits from slider, default = 1:1000
        
        % segmented branch colormap (proximal, distal1, distal2)
        branchseg_cmap      = [0, 0, 0; .9, .1, .2; .8, .8, 0; 0, .7, .9]; 
        
    end
        
    % Creator-defined functions
    methods (Access = private)
        
        % Image Stack Loader given an input name (uses ScanImageTiffReader.m)
        function [img] = load_tif_stack(app, fin) 
            
            % load image stack
            disp(['[' datestr(now) ']: Loading image stack...']);
            img_in = ScanImageTiffReader.ScanImageTiffReader(fin);
            
            % preprocess
            disp(['[' datestr(now) ']: Correcting FOV orientation...']);
            img = img_in.data;  % img_in is flipped L->R than rotated 90 from raw, readjusted in next line.
            img = rot90(img(:, size(img, 2):-1:1, :));
            hdrs = cellstr(splitlines(img_in.descriptions));
            
            % find integer value that corresponds to 0 from ScanImage header, apply normalization to stack so values respect 0.
            disp(['[' datestr(now) ']: Re-zeroing...']);
            offsets = str2num(cell2mat(strrep(hdrs(contains(hdrs, 'c0=')), 'c0=', '')));
            for i = 1:size(img, 3)
                img(:, :, i) = img(:, :, i) + offsets(i);
            end
            
            % set frame-selction slider limits so max reflects last frame of input stack
            app.FrameSelect_Slider.Limits = [1, size(img, 3)];
            
        end
        
        % Plot stack
        function plot_stack(app)
            
            % select sub-stack for plotting
            if app.frame_start > size(app.img, 3) - app.proj_window_size % last frames within window-size just include final window
                app.substack = app.img(:, :, (size(app.img, 3) - app.proj_window_size):end);
            else
                app.substack = app.img(:, :, app.frame_start:(app.frame_start + app.proj_window_size));
            end
            
            % run projection based on type, window, and frame select
            switch app.proj_type
                case 'none'
                    app.curr_proj = app.substack(:, :, 1);
                case 'mean'
                    app.curr_proj = mean(app.substack, 3);
                case 'max'
                    app.curr_proj = max(app.substack, [], 3); 
                case 'var'
                    app.curr_proj = var(single(app.substack), [], 3);
            end
            
            % plot current substack/projection
            app.Handles.InputImage = imagesc(app.InputImage, app.curr_proj); 
            hold(app.InputImage, 'on');
            axis(app.InputImage, [1, size(app.curr_proj, 1), 1, size(app.curr_proj, 2)]);
            
            % colormap
            cmap_instack(app, app.InputImage);
            
            % plot shaded ROIs over current projection, dependent on drawnrois switch - TODO
            
            
        end
        
        % Edit colormap for input stack or y-branch threshold
        function cmap_instack(app, uifig_id)
            
            % adjust colormap according to user-specified limits
            colormap(uifig_id, [zeros(1000, 1), linspace(0, 1, 1000)', zeros(1000, 1)]);
            caxis(uifig_id, [app.cmap_min, app.cmap_max]);
            
        end
        
        % Edit colormap for input stack or y-branch threshold
        function cmap_thresh(app, uifig_id)
            
            % adjust colormap according to user-specified limits
            colormap(uifig_id, [0, 0, 0; 0, 1, 0]);
            caxis(uifig_id, [0, 1]);
            
        end
        
        % Plot thresholded ybranch 
        function plot_ybranch_thresh(app)
            
            % get current mask pad mask to fit in a square with some empty space around it
            branch_proj = app.ybranch.rois(app.fov_rois_counter).cropped.mask_proj_nopad;
            branch_data = app.ybranch.rois(app.fov_rois_counter).cropped.mask_data_nopad;
            
            % threshold value for selecting dendritic branch (threshold is fraction relative to max signal in current mask's projection)
            thresh      = app.thresh_val / app.thresh_lim(2); % normalize value to a max of 1
            
            % gaussian filter
            projsmooth  = imgaussfilt(branch_proj, 2, 'FilterSize', 3, 'Padding', 'replicate', 'FilterDomain', 'spatial');  
            
            % extract dendrite from background
            bw_extract      = bwlabel(projsmooth > (thresh * max(max(app.curr_proj))));     % extract connected components greater than threshold 
            bw_extract_tab  = tabulate(bw_extract(:));                                      % tabulate size of connected components
            branchmask      = (bw_extract == (find(bw_extract_tab(2:end, 2) == max(bw_extract_tab(2:end, 2)))));     % take largest connected component (assumed to be the branch)
            
            % the mask to plot should be the branch mask after thresholding & extraction 
            plot_mask = branchmask;
            plot_proj = projsmooth;
            major_pad = 5;
            majdim = find(size(plot_mask) == max(size(plot_mask)), 1);
            plot_mask_padded = zeros(size(plot_mask, majdim) + (2 * major_pad));
            plot_proj_padded = zeros(size(plot_mask, majdim) + (2 * major_pad));
            data_in_padded   = zeros(size(plot_mask, majdim) + (2 * major_pad), size(plot_mask, majdim) + (2 * major_pad), size(branch_data, 3));
            proj_in_padded   = zeros(size(plot_mask, majdim) + (2 * major_pad));
            mindim = abs(majdim - 3);
            minor_pad = (size(plot_mask_padded, 1) - size(plot_mask, mindim)) / 2;
            if majdim == 1
                plot_mask_padded((major_pad + 1):(major_pad + size(plot_mask, 1)), (minor_pad + 1):(minor_pad + size(plot_mask, 2))) = plot_mask;
                plot_proj_padded((major_pad + 1):(major_pad + size(plot_mask, 1)), (minor_pad + 1):(minor_pad + size(plot_mask, 2))) = plot_proj;
                data_in_padded((major_pad + 1):(major_pad + size(plot_mask, 1)), (minor_pad + 1):(minor_pad + size(plot_mask, 2)), :) = branch_data;
                proj_in_padded((major_pad + 1):(major_pad + size(plot_mask, 1)), (minor_pad + 1):(minor_pad + size(plot_mask, 2))) = branch_proj;
            else
                plot_mask_padded((minor_pad + 1):(minor_pad + size(plot_mask, 1)), (major_pad + 1):(major_pad + size(plot_mask, 2))) = plot_mask;
                plot_proj_padded((minor_pad + 1):(minor_pad + size(plot_mask, 1)), (major_pad + 1):(major_pad + size(plot_mask, 2))) = plot_proj;
                data_in_padded((minor_pad + 1):(minor_pad + size(plot_mask, 1)), (major_pad + 1):(major_pad + size(plot_mask, 2)), :) = branch_data;
                proj_in_padded((minor_pad + 1):(minor_pad + size(plot_mask, 1)), (major_pad + 1):(major_pad + size(plot_mask, 2))) = branch_proj;
            end
            
            % plot
            app.Handles.ThreshMask = imagesc(app.YBranchThreshold_Plot, plot_mask_padded);
            axis(app.YBranchThreshold_Plot, 'tight');
            
            % sink output data to ybranch structure
            app.ybranch.rois(app.fov_rois_counter).cropped.threshold        = thresh;           % threshold for subselected X-Y branch image after smoothing (used to grab branch ROI from bwlabel output
            app.ybranch.rois(app.fov_rois_counter).cropped.majordim_padsz   = major_pad;        % padding size used for turning mask into padded mask
            app.ybranch.rois(app.fov_rois_counter).cropped.mask_proj        = proj_in_padded;   % smoothed projection within current mask
            app.ybranch.rois(app.fov_rois_counter).cropped.mask_data        = data_in_padded;   % branch mask after thresholding and extracting
            app.ybranch.rois(app.fov_rois_counter).cropped.mask_proj_smooth = plot_proj_padded; % smoothed projection within current mask
            app.ybranch.rois(app.fov_rois_counter).cropped.fullbranchmask   = plot_mask_padded; % branch
            
            % remove some unused fields that take up data -- NEED TO DO
            % THIS SOMEWHERE ELSE CLOSER TO WRITING OUT::::: PROBABLY ON
            % SAVE OUTPUT BUTTON
%             app.ybranch.rois(app.fov_rois_counter).cropped = rmfield(app.ybranch.rois(app.fov_rois_counter).cropped, {'mask_proj_nopad', 'mask_data_nopad'});
            
            % recolor
            cmap_thresh(app, app.YBranchThreshold_Plot);
            
        end
        
        % Plot segmented ybranch 
        function plot_ybranch_segment(app)
            
            % clear plot
            cla(app.YBranchSegment_Plot);
            
            % get ybranch necessary pieces
            branchmask      = app.ybranch.rois(app.fov_rois_counter).cropped.fullbranchmask;
            branchpointmask = app.ybranch.rois(app.fov_rois_counter).cropped.branchpoint.mask; 
            centroid        = app.ybranch.rois(app.fov_rois_counter).cropped.branchpoint.centroid;
            
            % separate Y-branch segments
            all_masks = bwlabel(branchmask .* ~branchpointmask);
            
            % run branch comparison
            branchcompare = ybranch_fit_splines(app, all_masks, centroid); % branches named arbitrarily 1, 2, 3
            
            % angles between splines
            branchcompare_final = ybranch_get_angles(app, branchcompare); % branches now named according to proximal/distal
            
            % plot splines
            bnames = {'proximal', 'distal1', 'distal2'};
            for i = 1:size(bnames, 2)
                                
                % get branch comparison data for current branch
                bmask       = eval(['branchcompare_final.' bnames{i} '.mask']);
                spline_fit  = eval(['branchcompare_final.' bnames{i} '.spline_fit']);
                
                % plotter
                mm = imagesc(app.YBranchSegment_Plot, (bmask .* i)); 
                hold(app.YBranchSegment_Plot, 'on');
                set(mm, 'AlphaData', (logical(bmask) .* (4/5)) );
                plot(app.YBranchSegment_Plot, spline_fit(:, 1), spline_fit(:, 2), 'k', 'LineWidth', 2);
                
                % clean up
                clear bmask spline_fit mm
                
            end
            
            % sync values to ybranch structure
            app.ybranch.rois(app.fov_rois_counter).segmented = branchcompare_final;
            
            % plot midpoint
            scatter(app.YBranchSegment_Plot, centroid(1), centroid(2), 200, 'r', 'filled'); 
            
            % some axis properties
            colormap(app.YBranchSegment_Plot, app.branchseg_cmap);
            axis(app.YBranchSegment_Plot, 'tight');

        end
        
        % Calculate splines for Y-branch segments
        function [bcomp] = ybranch_fit_splines(app, all_masks, centroid)
            
            % bcompare structure
            bcomp = []; 
            bcomp.masks_all = all_masks;
            bcomp.centroid  = centroid;
            
            % loop through all_masks, fitting smoothing spline
            for i = 1:size(unique(bcomp.masks_all(bcomp.masks_all > 0)), 1)
                
                % current mask name (temp until angles can be compared)
                bname = eval(['''branch' num2str(i) '''']);
                
                % current mask
                bmask = (bcomp.masks_all == i);
                            
                % get coordinates in box and fit split, append branch point centroid
                [yc, xc]    = find(bcomp.masks_all == i);     % all points within branch
                xc          = [xc; bcomp.centroid(1)];
                yc          = [yc; bcomp.centroid(2)];
                
                % fit spline to x-y data for branch, x-natural
                pp = polyfix(xc, yc, 3, xc(end), yc(end));
                py = polyval(pp, xc);
                spline_fit = sortrows([xc, py]);
                
                % remove xc & yc values crossing threshold of branchpoint
                if median(xc) < bcomp.centroid(1)
                    if median(yc) < bcomp.centroid(2)
                        spline_fit = spline_fit(round(spline_fit(:, 1), 4) <= round(bcomp.centroid(1), 4) & round(spline_fit(:, 2), 4) <= round(bcomp.centroid(2), 4), :);
                    else
                        spline_fit = spline_fit(round(spline_fit(:, 1), 4) <= round(bcomp.centroid(1), 4) & round(spline_fit(:, 2), 4) >= round(bcomp.centroid(2), 4), :);
                    end
                else
                    if median(yc) < bcomp.centroid(2)
                        spline_fit = spline_fit(round(spline_fit(:, 1), 4) >= round(bcomp.centroid(1), 4) & round(spline_fit(:, 2), 4) <= round(bcomp.centroid(2), 4), :);
                    else
                        spline_fit = spline_fit(round(spline_fit(:, 1), 4) >= round(bcomp.centroid(1), 4) & round(spline_fit(:, 2), 4) >= round(bcomp.centroid(2), 4), :);
                    end
                end
                
                % sink values
                eval(['bcomp.' bname '.mask = bmask;']);
                eval(['bcomp.' bname '.spline_fit = spline_fit;']);
                
                % clean up
                clear bname bmask spline_fit yc xc pp py mm
                
            end

        end
        
        % get angles between splines
        function [bcomp] = ybranch_get_angles(app, bcomp)
            
            % calculate angle between each spline, determine proximal (relative to soma) vs. two distal components
            fnames = fieldnames(bcomp);
            bnames = fnames(contains(fnames, 'branch')); % branches named arbitrarily 1, 2, 3
            offset = 4; % points to offset from branch point -- could use zero but slightly odd behavior for splines that converge on branch point from far away
            for i = 1:size(bnames, 1)
                
                % get last point of spline (separated by offset) leading up to branch point center
                nb1 = setdiff(eval(['bcomp.' bnames{i} '.spline_fit']), bcomp.centroid, 'rows');
                if pdist([eval(['bcomp.' bnames{i} '.spline_fit(end, :)'])', bcomp.centroid']') < pdist([eval(['bcomp.' bnames{i} '.spline_fit(1, :)'])', bcomp.centroid']')
                    x1 = nb1(end - offset, 1);
                    y1 = nb1(end - offset, 2);
                else
                    x1 = nb1(1 + offset, 1);
                    y1 = nb1(1 + offset, 2);
                end
                
                for j = 1:size(bnames, 1)
                    
                    % get last point of spline (separated by offset) leading up to branch point center
                    nb2 = setdiff(eval(['bcomp.' bnames{j} '.spline_fit']), bcomp.centroid, 'rows');
                    if pdist([eval(['bcomp.' bnames{j} '.spline_fit(end, :)'])', bcomp.centroid']') < pdist([eval(['bcomp.' bnames{j} '.spline_fit(1, :)'])', bcomp.centroid']')
                        x2 = nb2(end - offset, 1);
                        y2 = nb2(end - offset, 2);
                    else
                        x2 = nb2(1 + offset, 1);
                        y2 = nb2(1 + offset, 2);
                    end
                    
                    % calculate angle
                    v1 = [x1, y1, 0] - [bcomp.centroid(:, 1), bcomp.centroid(:, 2), 0];         % segment to centroid
                    v2 = [x2, y2, 0] - [bcomp.centroid(:, 1), bcomp.centroid(:, 2), 0];         % segment to centroid
                    bcomp.thetas(i, j) = atan2(norm(cross(v1, v2)), dot(v1, v2));    % radian difference
                    bcomp.angles(i, j) = rad2deg(bcomp.thetas(i, j));     % convert to degrees
                
                    % write angle onto plot between mask centroids
                    if i ~= j
                        midangpt = mean([x1, y1; x2, y2], 1);
                        dd = pdist([midangpt; bcomp.centroid]);
                        tx = bcomp.centroid(1) - ((40 * (bcomp.centroid(1) - midangpt(1))) / dd) + 2;
                        ty = bcomp.centroid(2) - ((50 * (bcomp.centroid(2) - midangpt(2))) / dd) + 10;
                        text(app.YBranchSegment_Plot, tx, ty, [sprintf('%.2f', bcomp.angles(i, j)) '' char(176)], 'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle', 'FontSize', 16);
                        clear midangpt cent
                    end
                    
                end
                
            end
            
            % write index of proximal/distal to structure
            ang_vec = bcomp.angles(logical(triu(bcomp.angles, 1))); % 1->2; 1->3; 2->3;
            if min(ang_vec) == ang_vec(1)
                bcomp.proximal = bcomp.branch3;
                if sort(ang_vec(~(ang_vec == min(ang_vec)))) == ang_vec(~(ang_vec == min(ang_vec)))
                    bcomp.distal1 = bcomp.branch2;
                    bcomp.distal2 = bcomp.branch1;
                else
                    bcomp.distal1 = bcomp.branch1;
                    bcomp.distal2 = bcomp.branch2;
                end
            elseif min(ang_vec) == ang_vec(2)
                bcomp.proximal = bcomp.branch2;
                if sort(ang_vec(~(ang_vec == min(ang_vec)))) == ang_vec(~(ang_vec == min(ang_vec)))
                    bcomp.distal1 = bcomp.branch3;
                    bcomp.distal2 = bcomp.branch1;
                else
                    bcomp.distal1 = bcomp.branch1;
                    bcomp.distal2 = bcomp.branch3;
                end
            elseif min(ang_vec) == ang_vec(3)
                bcomp.proximal = bcomp.branch1;
                if sort(ang_vec(~(ang_vec == min(ang_vec)))) == ang_vec(~(ang_vec == min(ang_vec)))
                    bcomp.distal1 = bcomp.branch3;
                    bcomp.distal2 = bcomp.branch2;
                else
                    bcomp.distal1 = bcomp.branch2;
                    bcomp.distal2 = bcomp.branch3;
                end
            end
            bcomp = rmfield(bcomp, {'branch1', 'branch2', 'branch3'});
            
        end
        
        % plot timecourses of segmented y-branch
        function plot_ybranch_timecourses(app)
            
            % get data from properties
            dat = app.ybranch.rois(app.fov_rois_counter).cropped.mask_data;
            seg = app.ybranch.rois(app.fov_rois_counter).segmented;
            
            % calculate time courses
            tc_prox = mean((reshape(dat, [size(dat, 1) * size(dat, 2), size(dat, 3)]) .* reshape(seg.proximal.mask, [size(dat, 1) * size(dat, 2), 1])), 1);
            tc_dist1 = mean((reshape(dat, [size(dat, 1) * size(dat, 2), size(dat, 3)]) .* reshape(seg.distal1.mask, [size(dat, 1) * size(dat, 2), 1])), 1);
            tc_dist2 = mean((reshape(dat, [size(dat, 1) * size(dat, 2), size(dat, 3)]) .* reshape(seg.distal2.mask, [size(dat, 1) * size(dat, 2), 1])), 1);
            
            % plot timecourses
            app.Handles.TC_prox  = plot(app.TC_Plot_Proximal, tc_prox, 'Color', app.branchseg_cmap(2, :), 'LineWidth', 2);
            app.Handles.TC_dist1 = plot(app.TC_Plot_Distal1, tc_dist1, 'Color', app.branchseg_cmap(3, :), 'LineWidth', 2);
            app.Handles.TC_dist2 = plot(app.TC_Plot_Distal2, tc_dist2, 'Color', app.branchseg_cmap(4, :), 'LineWidth', 2);
            
            % axis limits dependent on group max/min
            xmax = size(tc_prox, 2);
            ymax = max(max([tc_prox; tc_dist1; tc_dist2]));
            axis(app.TC_Plot_Proximal, [0, xmax, 0, 1.1 * ymax]);
            axis(app.TC_Plot_Distal1, [0, xmax, 0, 1.1 * ymax]);
            axis(app.TC_Plot_Distal2, [0, xmax, 0, 1.1 * ymax]);
            
            % lableling extras
            ll(1) = legend(app.TC_Plot_Proximal, 'proximal'); 
            ll(2) = legend(app.TC_Plot_Distal1, 'distal1'); 
            ll(3) = legend(app.TC_Plot_Distal2, 'distal2'); 
            set(ll, 'Box', 'off');
            ylabel(app.TC_Plot_Proximal, 'Raw F'); 
            ylabel(app.TC_Plot_Distal1, 'Raw F'); 
            ylabel(app.TC_Plot_Distal2, 'Raw F'); 
            xlabel(app.TC_Plot_Distal2, 'Frames');
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: OpenNewMenu
        function OpenNewMenuSelected(app, event)
            
            % get file
            [fn, fp] = uigetfile('*.tif*');
            app.current_file = fullfile(fp, fn);
            if isequal(app.current_file, 0)
               disp(['[' datestr(now) ']: User canceled file selection...']);
            else
               disp(['[' datestr(now) ']: Loading data file <' app.current_file '>...']);
               app.img = load_tif_stack(app, app.current_file);
            end
            
            if ~isempty(app.current_file)
                % reset components
                cla(app.InputImage);
                cla(app.YBranchThreshold_Plot);
                cla(app.YBranchSegment_Plot);
                cla(app.TC_Plot_Proximal);
                cla(app.TC_Plot_Distal1);
                cla(app.TC_Plot_Distal2);
                app.FrameSelect_Slider.Value = 1;
                FrameSelect_SliderValueChanged(app);
                app.FrameSelect_Spinner.Value = 1;
                FrameSelect_SpinnerValueChanged(app);
                app.Proj_NoneButton.Value = 1;
                Proj_ButtonGroupSelectionChanged(app);
                app.Proj_WindowSizeEditField.Value = 100;
                Proj_WindowSizeEditFieldValueChanged(app);
                app.ROISelectDropDown.Items = {'Select'};
                app.ROISelectDropDown.Value = 'Select';
                ROISelectDropDownValueChanged(app);
            end
            
            % initialize data structure properties of shared scope for the current input file
            app.ybranch = [];
            app.fov_rois = [];
            app.fov_rois_counter = 0;
            
            % put origin filename in ybranch data structure
            app.ybranch.provenance = app.current_file;
            
            % plot input stack
            plot_stack(app);
            
        end

        % Menu selected function: ReloadCurrentMenu
        function ReloadCurrentMenuSelected(app, event)
            
            % reload current file
            if isempty(app.current_file)
                
                % dialog box that no file selected
                msgbox({'No ''current'' input to load!'; 'Try: File -> Open -> Open New'}, 'Error', 'error');
                
            else
                
                mcinput = questdlg({'Warning!'; 'Unsaved progress on ROIs will be lost!'; 'Do you want to proceed?'});
                if strcmp(mcinput, 'Yes')
                
                    disp(['[' datestr(now) ']: Reloading data file <' app.current_file '>...']);
                    app.img = load_tif_stack(app, app.current_file);
                    
                    % reset components --- may want to change what is reset on reload when replotting same image... (keep ROIs, etc.)
                    cla(app.InputImage);
                    cla(app.YBranchThreshold_Plot);
                    cla(app.YBranchSegment_Plot);
                    cla(app.TC_Plot_Proximal);
                    cla(app.TC_Plot_Distal1);
                    cla(app.TC_Plot_Distal2);
                    app.FrameSelect_Slider.Value = 1;
                    FrameSelect_SliderValueChanged(app);
                    app.FrameSelect_Spinner.Value = 1;
                    FrameSelect_SpinnerValueChanged(app);
                    app.Proj_NoneButton.Value = 1;
                    Proj_ButtonGroupSelectionChanged(app);
                    app.Proj_WindowSizeEditField.Value = 100;
                    Proj_WindowSizeEditFieldValueChanged(app);
                    app.ROISelectDropDown.Items = {'Select'};
                    app.ROISelectDropDown.Value = 'Select';
                    ROISelectDropDownValueChanged(app);
                    
                    % initialize data structure properties of shared scope for the current input file
                    app.ybranch = [];
                    app.fov_rois = [];
                    app.fov_rois_counter = 0;
                    
                    % put origin filename in ybranch data structure
                    app.ybranch.provenance = app.current_file;
                    
                    % plot input stack
                    plot_stack(app);
                    
                end

            end
            
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            
            mcinput = questdlg({'Warning!'; 'GUI will shut down and progress on ROIs will be lost!'; 'Do you want to proceed?'});
            if strcmp(mcinput, 'Yes')
                
                % cancel reload if anything but "Yes"
                disp(['[' datestr(now) ']: GUI shutting down...']);
                close(app.UIFigure);
                
            end
            
        end

        % Menu selected function: HelpMenu
        function HelpMenuSelected(app, event)
            
            % Warn user that no help has been created yet
            msgbox('Yikes! Sorry, no help section has been created yet...', 'Warning', 'warn');
            
        end

        % Selection changed function: Proj_ButtonGroup
        function Proj_ButtonGroupSelectionChanged(app, event)
            
            selectedButton = app.Proj_ButtonGroup.SelectedObject;
            
            % command line feedback
            disp(['[' datestr(now) ']: Projection type set to ''' selectedButton.Text '''...']);
            
            % toggle projection type, set default min & max for toggle
            if strcmp(selectedButton.Text, 'None')
                app.proj_type = 'none';
                app.ColormapMinEditField.Value = 0;
                app.ColormapMaxEditField.Value = 1000;
            elseif strcmp(selectedButton.Text, 'Average')
                app.proj_type = 'mean';
                app.ColormapMinEditField.Value = 0;
                app.ColormapMaxEditField.Value = 1000;
            elseif strcmp(selectedButton.Text, 'Maximum')
                app.proj_type = 'max';
                app.ColormapMinEditField.Value = 0;
                app.ColormapMaxEditField.Value = 2500;
            elseif strcmp(selectedButton.Text, 'Variance')
                app.proj_type = 'var';
                app.ColormapMinEditField.Value = 0;
                app.ColormapMaxEditField.Value = 500000;
            end
            
            % colormap
            app.cmap_min = app.ColormapMinEditField.Value;
            app.cmap_max = app.ColormapMaxEditField.Value;
            
            % replot
            plot_stack(app);
            
        end

        % Value changed function: FrameSelect_Slider
        function FrameSelect_SliderValueChanged(app, event)

            % get value on change, set as first frame of projection
            value = app.FrameSelect_Slider.Value;
            app.frame_start = value;
            
            % make value change reciprocal with spinner
            app.FrameSelect_Spinner.Value = value;
            
            % replot
            plot_stack(app);
            
        end

        % Value changed function: FrameSelect_Spinner
        function FrameSelect_SpinnerValueChanged(app, event)
            
            % get value on change, set as first frame of projection
            value = app.FrameSelect_Spinner.Value;
            app.frame_start = value;
            
            % make value reciprocal with slider
            app.FrameSelect_Slider.Value = value;
            
            % replot
            plot_stack(app);
            
        end

        % Value changed function: Proj_WindowSizeEditField
        function Proj_WindowSizeEditFieldValueChanged(app, event)
            
            % get value change for window size
            value = app.Proj_WindowSizeEditField.Value;
            app.proj_window_size = value;
            
            % command line feedback
            disp(['[' datestr(now) ']: Projection window size set to ' num2str(value) '...'])
            
            % replot
            plot_stack(app);
            
        end

        % Value changed function: ColormapMinEditField
        function ColormapMinEditFieldValueChanged(app, event)
            
            % get value on change, set as colormap minimum
            value = app.ColormapMinEditField.Value;
            app.cmap_min = value;
            
            % replot
            plot_stack(app);
            
        end

        % Value changed function: ColormapMaxEditField
        function ColormapMaxEditFieldValueChanged(app, event)
                        
            % get value on change, set as colormap maximum
            value = app.ColormapMaxEditField.Value;
            app.cmap_max = value;
            
            % replot
            plot_stack(app);

        end

        % Button pushed function: SS_DrawNewButton
        function SS_DrawNewButtonPushed(app, event)
            
            bpush = 1;
            while bpush == 1
                
                % sanity check for whether currently drawn ROI has been marked complete
                if app.new_draw_resolved == 0
                    
                    mcinput = questdlg({'Warning!'; 'Current ROI not marked complete and will be deleted!'; 'Do you want to proceed?'});
                    if or(strcmp(mcinput, 'No'), strcmp(mcinput, 'Cancel'))
                        bpush = 0;
                    else
                        app.new_draw_resolved = 1;
                    end
                    
                else
                    
                    % For polygons drawn on FOV, start at 1 or increment depending on already drawn 
                    if isempty(app.fov_rois)
                        app.fov_rois = [];
                        app.fov_rois.init = 1;
                        app.fov_rois_counter = 1;
                        app.new_draw_resolved = 1;
                    else
                        app.fov_rois_counter = (size(app.fov_rois, 2) + 1);     % increment counter to current ROI
                    end
                    
                    % draw new roi on the full fov
                    fov_rois_opts = [];
                    fov_rois_opts.Label                 = num2str(app.fov_rois_counter);
                    fov_rois_opts.LineWidth             = 2; 
                    fov_rois_opts.Selected              = 0;                % automatically select the current drawing
                    fov_rois_opts.SelectedColor         = [.9, .3, .1];     % selection color is orange
                    fov_rois_opts.Color                 = [.1, .5, .9];     % default color is blue
                    fov_rois_opts.InteractionsAllowed   = 'none';           % 'none' will lock ROI to edits
                    fov_rois_opts.FaceSelectable        = 1;
                    app.fov_rois(app.fov_rois_counter).Draw = drawpolygon(app.InputImage, fov_rois_opts);   % mask to sink
                    app.fov_rois(app.fov_rois_counter).init = 1;
                    
                    % Add newly drawn ROI to dropdown & select
                    app.ROISelectDropDown.Items = [app.ROISelectDropDown.Items, num2str(app.fov_rois_counter)];
                    app.ROISelectDropDown.Value = num2str(app.fov_rois_counter);
                    ROISelectDropDownValueChanged(app);
                    
                    % mark drawn unresolved
                    app.new_draw_resolved = 0;          % 0 = new drawing not marked complete, 1 = new drawing marked complete
                
                    % end while condition
                    bpush = 0;
                    
                end
                
            end
            
        end

        % Button pushed function: SS_MarkCompleteButton
        function SS_MarkCompleteButtonPushed(app, event)
            
            % Write current roi mask & centroid to ybranch structure
            disp(['[' datestr(now) ']: ROI ' num2str(app.fov_rois_counter) ' marked complete, extracting sub-selected data for segmentation...']);
            
            % vertices & crop
            vx          = round(app.fov_rois(app.fov_rois_counter).Draw.Position(:, 1)); 
            vy          = round(app.fov_rois(app.fov_rois_counter).Draw.Position(:, 2)); 
            cropx       = min(vx):max(vx);
            cropy       = min(vy):max(vy);
            
            % mask in original fov and cropped (mask, projection, data);
            % note: remember that y is 1st dim in matrix notation, x is 2nd dim
            mask        = poly2mask(vx, vy, size(app.curr_proj, 1), size(app.curr_proj, 2));
            maskcrop    = mask(cropy, cropx);
            maskproj    = uint16(single(app.curr_proj(cropy, cropx)) .* maskcrop);
            maskdata    = uint16(reshape((reshape(single(app.img(cropy, cropx, :)), [size(maskcrop, 1) * size(maskcrop, 2), size(app.img, 3)]) .* reshape(maskcrop, [size(maskcrop, 1) * size(maskcrop, 2), 1])), [size(maskcrop, 1), size(maskcrop, 2), size(app.img, 3)]));
            
            % sink output data to ybranch structure
            app.ybranch.rois(app.fov_rois_counter).fullfov.vertices_x   = vx;
            app.ybranch.rois(app.fov_rois_counter).fullfov.vertices_y   = vy;
            app.ybranch.rois(app.fov_rois_counter).fullfov.mask_nopad   = mask;
            app.ybranch.rois(app.fov_rois_counter).cropped.mask_nopad   = maskcrop;
            app.ybranch.rois(app.fov_rois_counter).cropped.mask_proj_nopad  = maskproj;
            app.ybranch.rois(app.fov_rois_counter).cropped.mask_data_nopad  = maskdata;
            
            % mark complete
            app.new_draw_resolved = 1;
            app.thresh_val = 1;
            app.thresh_lim = [0, 1000];
            
            % plot completed ybranch to app.YBranchPlots
            plot_ybranch_thresh(app);

        end

        % Value changed function: Thresh_SetThresholdSlider
        function Thresh_SetThresholdSliderValueChanged(app, event)
            
            % read slider properties
            value   = app.Thresh_SetThresholdSlider.Value;
            limits  = app.Thresh_SetThresholdSlider.Limits;
            app.thresh_val = value;
            app.thresh_lim = limits;
            
            % plot completed ybranch to app.YBranchPlots
            if ~isempty(app.ybranch) % check to see if we have anything to plot
                plot_ybranch_thresh(app);
            end
            
        end

        % Button pushed function: Thresh_DrawNewButton
        function Thresh_DrawNewButtonPushed(app, event)
            
            bpush = 1;
            while bpush == 1
                
                % sanity check for whether currently drawn ROI has been marked complete
                if isempty(app.ybranch.rois(app.fov_rois_counter).cropped.fullbranchmask)
                    
                    msgbox({'Error!'; 'Err... Something is wrong!'; 'We''re not seeing an Y-branch here to draw a mask on!'; 'Try something else and come back...'}, 'Error', 'error');
                    bpush = 0;
                    
                else
                    
                    % draw branch point mask on Y-branch
                    bp_roi_opts = [];
                    bp_roi_opts.Label         = num2str(app.fov_rois_counter);
                    bp_roi_opts.LineWidth     = 2; 
                    bp_roi_opts.Selected      = 1;                % automatically select the current drawing
                    bp_roi_opts.SelectedColor = [.9, .3, .1];     % selection color is orange
                    bp_roi_opts.Color         = [.1, .5, .9];     % default color is blue
                    bp = drawpolygon(app.YBranchThreshold_Plot, bp_roi_opts);   % mask to sink
                    
                    % sync out the branch point mask
                    app.ybranch.rois(app.fov_rois_counter).cropped.branchpoint.x          = bp.Position(:, 2);
                    app.ybranch.rois(app.fov_rois_counter).cropped.branchpoint.y          = bp.Position(:, 1);
                    app.ybranch.rois(app.fov_rois_counter).cropped.branchpoint.centroid   = [mean(bp.Position(:, 1)), mean(bp.Position(:, 2))];
                    app.ybranch.rois(app.fov_rois_counter).cropped.branchpoint.mask       = poly2mask(bp.Position(:, 1), bp.Position(:, 2), size(app.ybranch.rois(app.fov_rois_counter).cropped.fullbranchmask, 1), size(app.ybranch.rois(app.fov_rois_counter).cropped.fullbranchmask, 2));
                    
                    % end while condition
                    bpush = 0;
                    
                end
                
            end
        end

        % Button pushed function: Thresh_SegmentButton
        function Thresh_SegmentButtonPushed(app, event)

                    % plot segmented branches with splines
                    plot_ybranch_segment(app);
                    
                    % plot timecourses for segmented branches
                    plot_ybranch_timecourses(app);

        end

        % Value changed function: ROISelectDropDown
        function ROISelectDropDownValueChanged(app, event)
            
            % get value for current selection
            value = app.ROISelectDropDown.Value;
            vv = str2num(value);
            
            % highlight selected ROI mask on input image
            if ~strcmp(value, 'Select')
                disp(['[' datestr(now) ']: ROI ' num2str(vv) ' selected...']);
                for ii = 1:size(app.fov_rois, 2)
                    if ii == vv
                        app.fov_rois_counter = ii;
                        app.fov_rois(ii).Draw.Selected = 1;
                        app.fov_rois(ii).Draw.Visible = 'on';
                        app.fov_rois(ii).Draw.InteractionsAllowed = 'all';
                    else
                        app.fov_rois(ii).Draw.Selected = 0;
                        if strcmp(app.DrawnROIsSwitch.Value, 'Hide')
                            app.fov_rois(ii).Draw.Visible = 'off';
                        end
                        app.fov_rois(ii).Draw.InteractionsAllowed = 'none';
                    end
                end
            end
            
        end

        % Button pushed function: SS_DeleteButton
        function SS_DeleteButtonPushed(app, event)
            
            % command line feedback
            disp(['[' datestr(now) ']: Deleting ROI ' num2str(app.fov_rois_counter) ' and re-distributing labels...']);
            
            % re-show all ROIs
            app.DrawnROIsSwitch.Value = 'Show';
            DrawnROIsSwitchValueChanged(app);
            
            % delete current selection
            app.fov_rois(app.fov_rois_counter).Draw.Visible = 'off';
            app.ROISelectDropDown.Items = app.ROISelectDropDown.Items(1:end-1);
            
            % increment remaining rois down in number
            ii = app.fov_rois_counter;
            while ii < size(app.ybranch.rois, 2)
                
                % shift data down in ybranch and fov_rois
                app.ybranch.rois(ii) = app.ybranch.rois(ii + 1);
                app.fov_rois(ii) = app.fov_rois(ii + 1);
                
                % relabel ROIs that were incremented down
                app.fov_rois(ii).Draw.Label = num2str(ii);
                
                % increment counter
                ii = ii + 1;
                
            end
            
            % empty out final structure entry, which has now been shifted down a slot
            app.ybranch.rois(size(app.ybranch.rois, 2)) = [];
            app.fov_rois(size(app.fov_rois, 2)) = [];
            
            % re-select ROI from dropdown menu
            ROISelectDropDownValueChanged(app);
            
        end

        % Value changed function: DrawnROIsSwitch
        function DrawnROIsSwitchValueChanged(app, event)
            
            value = app.DrawnROIsSwitch.Value;
            
            if strcmp(value, 'Hide')
                
                % command line feedback
                disp(['[' datestr(now) ']: Hiding unselected ROIs...']);
                
                % hide ROIs except selection (according to app.fov_rois_counter
                for ii = [1:app.fov_rois_counter-1, app.fov_rois_counter+1:size(app.fov_rois, 2)]
                    app.fov_rois(ii).Draw.Visible = 'off';
                end
                
            elseif strcmp(value, 'Show')
                
                % command line feedback
                disp(['[' datestr(now) ']: Showing unselected ROIs...']);
                
                % hide ROIs except selection (according to app.fov_rois_counter
                for ii = [1:app.fov_rois_counter-1, app.fov_rois_counter+1:size(app.fov_rois, 2)]
                    app.fov_rois(ii).Draw.Visible = 'on';
                end
                
            end

        end

        % Button pushed function: TC_ExitButton
        function TC_ExitButtonPushed(app, event)
            
            mcinput = questdlg({'Warning!'; 'GUI will shut down and unsaved progress on ROIs will be lost!'; 'Do you want to proceed?'});
            if strcmp(mcinput, 'Yes')
                
                % cancel reload if anything but "Yes"
                disp(['[' datestr(now) ']: GUI shutting down...']);
                close(app.UIFigure);
                
            end
            
        end

        % Button pushed function: TC_RefreshButton
        function TC_RefreshButtonPushed(app, event)
            
            mcinput = questdlg({'Warning!'; 'All figures will be cleared and unsaved progress on ROIs will be lost!'; 'Do you want to proceed?'});
            if strcmp(mcinput, 'Yes')
                
                % cancel reload if anything but "Yes"
                disp(['[' datestr(now) ']: Refreshing GUI...']);
                
                % clear out current file & data structures
                app.current_file = [];
                app.fov_rois = [];
                app.ybranch = [];
                
                % flush components & restore defaults
                app.fov_rois_counter = 0;
                cla(app.InputImage);
                cla(app.YBranchThreshold_Plot);
                cla(app.YBranchSegment_Plot);
                cla(app.TC_Plot_Proximal);
                cla(app.TC_Plot_Distal1);
                cla(app.TC_Plot_Distal2);
                app.FrameSelect_Slider.Value = 1;
                FrameSelect_SliderValueChanged(app);
                app.FrameSelect_Spinner.Value = 1;
                FrameSelect_SpinnerValueChanged(app);
                app.Proj_NoneButton.Value = 1;
                Proj_ButtonGroupSelectionChanged(app);
                app.Proj_WindowSizeEditField.Value = 100;
                Proj_WindowSizeEditFieldValueChanged(app);
                app.ROISelectDropDown.Items = {'Select'};
                app.ROISelectDropDown.Value = 'Select';
                ROISelectDropDownValueChanged(app);
                
            end
            
        end

        % Value changed function: BranchSelectDropDown
        function BranchSelectDropDownValueChanged(app, event)
            
            % get value for current selection
            value = app.BranchSelectDropDown.Value;
            vv = str2num(value);
            
            % highlight selected ROI mask on input image
            if ~strcmp(value, 'Select')
                disp(['[' datestr(now) ']: Branch ' num2str(vv) ' selected...']);
                for ii = 1:size(app.fov_rois, 2)
                    if ii == vv
%                         app.fov_rois(ii).Draw.Selected = 1;
%                         app.fov_rois(ii).Draw.Visible = 'on';
%                         app.fov_rois(ii).Draw.InteractionsAllowed = 'all';
                    else
%                         app.fov_rois(ii).Draw.Selected = 0;
%                         app.fov_rois(ii).Draw.InteractionsAllowed = 'none';
                    end
                end
            end
            
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {886, 886};
                app.GridLayout.ColumnWidth = {'1x'};
                app.YBranchPlots.Layout.Row = 2;
                app.YBranchPlots.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {605, '1x'};
                app.YBranchPlots.Layout.Row = 1;
                app.YBranchPlots.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.Text = 'Open';
            app.OpenNewMenu = uimenu(app.OpenMenu);
            app.OpenNewMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenNewMenuSelected, true);
            app.OpenNewMenu.Text = 'Open New';
            app.ReloadCurrentMenu = uimenu(app.OpenMenu);
            app.ReloadCurrentMenu.MenuSelectedFcn = createCallbackFcn(app, @ReloadCurrentMenuSelected, true);
            app.ReloadCurrentMenu.Text = 'Reload Current';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.MenuSelectedFcn = createCallbackFcn(app, @HelpMenuSelected, true);
            app.HelpMenu.Text = 'Help';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.StackIn = uipanel(app.GridLayout);
            app.InputImage = uiaxes(app.StackIn);
            title(app.InputImage, 'Input Image Stack')
            xlabel(app.InputImage, '')
            ylabel(app.InputImage, '')
            app.InputImage.XTick = [];
            app.InputImage.YTick = [];
            app.SS_label = uilabel(app.StackIn);
            app.SS_label.Text = {'Input Stack Y-Branch Selector'; '1) Click DRAW NEW to draw ROI (polygon) around dendritic Y-branch.'; '2) Click SELECT to decode branches.'; '3) Edit & Delete old ROIs if needed (for edited ROIs, must re-SELECT).'};
            app.FrameSelectLabel = uilabel(app.StackIn);
            app.FrameSelectLabel.Text = 'Frame Select';
            app.FrameSelect_Slider = uislider(app.StackIn);
            app.FrameSelect_Slider.MajorTicks = [];
            app.FrameSelect_Slider.ValueChangedFcn = createCallbackFcn(app, @FrameSelect_SliderValueChanged, true);
            app.SS_DrawNewButton = uibutton(app.StackIn, 'push');
            app.SS_DrawNewButton.ButtonPushedFcn = createCallbackFcn(app, @SS_DrawNewButtonPushed, true);
            app.SS_DrawNewButton.Text = 'Draw New';
            app.Proj_ButtonGroup = uibuttongroup(app.StackIn);
            app.Proj_ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Proj_ButtonGroupSelectionChanged, true);
            app.Proj_ButtonGroup.Title = 'Projection Method (select window size)';
            app.Proj_NoneButton = uiradiobutton(app.Proj_ButtonGroup);
            app.Proj_NoneButton.Text = 'None';
            app.Proj_NoneButton.Value = true;
            app.Proj_MaxButton = uiradiobutton(app.Proj_ButtonGroup);
            app.Proj_MaxButton.Text = 'Maximum';
            app.Proj_VarButton = uiradiobutton(app.Proj_ButtonGroup);
            app.Proj_VarButton.Text = 'Variance';
            app.Proj_AvgButton = uiradiobutton(app.Proj_ButtonGroup);
            app.Proj_AvgButton.Text = 'Average';
            app.WindowSizeframesLabel = uilabel(app.StackIn);
            app.WindowSizeframesLabel.Text = {'Window Size'; '(# frames)'};
            app.Proj_WindowSizeEditField = uieditfield(app.StackIn, 'numeric');
            app.Proj_WindowSizeEditField.Limits = [1 Inf];
            app.Proj_WindowSizeEditField.RoundFractionalValues = 'on';
            app.Proj_WindowSizeEditField.ValueChangedFcn = createCallbackFcn(app, @Proj_WindowSizeEditFieldValueChanged, true);
            app.Proj_WindowSizeEditField.Value = 100;
            app.SS_MarkCompleteButton = uibutton(app.StackIn, 'push');
            app.SS_MarkCompleteButton.ButtonPushedFcn = createCallbackFcn(app, @SS_MarkCompleteButtonPushed, true);
            app.SS_MarkCompleteButton.Text = 'Mark Complete';
            app.SS_DeleteButton = uibutton(app.StackIn, 'push');
            app.SS_DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @SS_DeleteButtonPushed, true);
            app.SS_DeleteButton.Text = 'Delete';
            app.FrameSelect_Spinner = uispinner(app.StackIn);
            app.FrameSelect_Spinner.Limits = [1 Inf];
            app.FrameSelect_Spinner.RoundFractionalValues = 'on';
            app.FrameSelect_Spinner.ValueChangedFcn = createCallbackFcn(app, @FrameSelect_SpinnerValueChanged, true);
            app.FrameSelect_Spinner.Value = 1;
            app.MinEditFieldLabel = uilabel(app.StackIn);
            app.MinEditFieldLabel.Text = 'Min';
            app.ColormapMinEditField = uieditfield(app.StackIn, 'numeric');
            app.ColormapMinEditField.Limits = [0 Inf];
            app.ColormapMinEditField.ValueChangedFcn = createCallbackFcn(app, @ColormapMinEditFieldValueChanged, true);
            app.MaxEditFieldLabel = uilabel(app.StackIn);
            app.MaxEditFieldLabel.Text = 'Max';
            app.ColormapMaxEditField = uieditfield(app.StackIn, 'numeric');
            app.ColormapMaxEditField.Limits = [0 Inf];
            app.ColormapMaxEditField.ValueChangedFcn = createCallbackFcn(app, @ColormapMaxEditFieldValueChanged, true);
            app.ColormapMaxEditField.Value = 1000;
            app.ColormapEditLabel = uilabel(app.StackIn);
            app.ColormapEditLabel.Text = 'Colormap';
            app.DrawnROIsLabel = uilabel(app.StackIn);
            app.DrawnROIsLabel.Text = 'Drawn ROIs';
            app.DrawnROIsSwitch = uiswitch(app.StackIn, 'slider');
            app.DrawnROIsSwitch.Items = {'Hide', 'Show'};
            app.DrawnROIsSwitch.ValueChangedFcn = createCallbackFcn(app, @DrawnROIsSwitchValueChanged, true);
            app.DrawnROIsSwitch.Value = 'Show';
            app.ROISelectDropDownLabel = uilabel(app.StackIn);
            app.ROISelectDropDownLabel.Text = 'ROI Select';
            app.ROISelectDropDown = uidropdown(app.StackIn);
            app.ROISelectDropDown.Items = {'Select'};
            app.ROISelectDropDown.ValueChangedFcn = createCallbackFcn(app, @ROISelectDropDownValueChanged, true);
            app.ROISelectDropDown.Value = 'Select';
            app.YBranchPlots = uipanel(app.GridLayout);
            app.YBranchThreshold_Plot = uiaxes(app.YBranchPlots);
            title(app.YBranchThreshold_Plot, 'Y-Branch, Thresholded')
            xlabel(app.YBranchThreshold_Plot, '')
            ylabel(app.YBranchThreshold_Plot, '')
            app.YBranchThreshold_Plot.XTick = [];
            app.YBranchThreshold_Plot.YTick = [];
            app.TimecoursesPanel = uipanel(app.YBranchPlots);
            app.TimecoursesPanel.Title = 'Timecourses of Segmented Y-branch';
            app.TC_Plot_Proximal = uiaxes(app.TimecoursesPanel);
            title(app.TC_Plot_Proximal, '')
            xlabel(app.TC_Plot_Proximal, '')
            ylabel(app.TC_Plot_Proximal, '')
            app.TC_Plot_Proximal.XTick = [];
            app.TC_Plot_Proximal.YTick = [];
            app.TC_Plot_Proximal.YTickLabel = '';
            app.TC_SyncROIsButton = uibutton(app.TimecoursesPanel, 'push');
            app.TC_SyncROIsButton.Text = 'Sync ROIs';
            app.TC_SaveAsButton = uibutton(app.TimecoursesPanel, 'push');
            app.TC_SaveAsButton.Text = 'Save As';
            app.TC_ExitButton = uibutton(app.TimecoursesPanel, 'push');
            app.TC_ExitButton.ButtonPushedFcn = createCallbackFcn(app, @TC_ExitButtonPushed, true);
            app.TC_ExitButton.Text = 'Exit';
            app.TC_Plot_Distal1 = uiaxes(app.TimecoursesPanel);
            title(app.TC_Plot_Distal1, '')
            xlabel(app.TC_Plot_Distal1, '')
            ylabel(app.TC_Plot_Distal1, '')
            app.TC_Plot_Distal1.XTick = [];
            app.TC_Plot_Distal1.YTick = [];
            app.TC_Plot_Distal1.YTickLabel = '';
            app.TC_Plot_Distal2 = uiaxes(app.TimecoursesPanel);
            title(app.TC_Plot_Distal2, '')
            xlabel(app.TC_Plot_Distal2, '')
            ylabel(app.TC_Plot_Distal2, '')
            app.TC_Plot_Distal2.XTick = [];
            app.TC_Plot_Distal2.YTick = [];
            app.TC_Plot_Distal2.YTickLabel = '';
            app.TC_RefreshButton = uibutton(app.TimecoursesPanel, 'push');
            app.TC_RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @TC_RefreshButtonPushed, true);
            app.TC_RefreshButton.Text = 'Refresh';
            app.YBranchSegment_Plot = uiaxes(app.YBranchPlots);
            title(app.YBranchSegment_Plot, 'Y-Branch, Segmented')
            xlabel(app.YBranchSegment_Plot, '')
            ylabel(app.YBranchSegment_Plot, '')
            app.YBranchSegment_Plot.XTick = [];
            app.YBranchSegment_Plot.YTick = [];
            app.ThreshAndSeg_Label = uilabel(app.YBranchPlots);
            app.ThreshAndSeg_Label.Text = {'Y-Branch Threshold & Segment:'; '1) Use Threshold slider to segment out dendrite Y-branch from background.'; '2) Click DRAW MASK to draw polygon masking out the branch point (separates Y components).'; '3) Select ROI type, then click SEGMENT to update segmentation and plot timecourses.'};
            app.ROITypeButtonGroup = uibuttongroup(app.YBranchPlots);
            app.ROITypeButtonGroup.Title = 'ROI Type';
            app.ROI_ContourButton = uiradiobutton(app.ROITypeButtonGroup);
            app.ROI_ContourButton.Text = 'Contour';
            app.ROI_ContourButton.Value = true;
            app.ROI_RectangleButton = uiradiobutton(app.ROITypeButtonGroup);
            app.ROI_RectangleButton.Text = 'Rectangle';
            app.ROI_SplineButton = uiradiobutton(app.ROITypeButtonGroup);
            app.ROI_SplineButton.Text = 'Spline';
            app.SetThresholdSliderLabel = uilabel(app.YBranchPlots);
            app.SetThresholdSliderLabel.Text = 'Set Threshold';
            app.Thresh_SetThresholdSlider = uislider(app.YBranchPlots);
            app.Thresh_SetThresholdSlider.Limits = [1 1000];
            app.Thresh_SetThresholdSlider.MajorTicks = [];
            app.Thresh_SetThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @Thresh_SetThresholdSliderValueChanged, true);
            app.Thresh_SetThresholdSlider.Value = 1;
            app.Thresh_DrawNewButton = uibutton(app.YBranchPlots, 'push');
            app.Thresh_DrawNewButton.ButtonPushedFcn = createCallbackFcn(app, @Thresh_DrawNewButtonPushed, true);
            app.Thresh_DrawNewButton.Text = 'Draw New';
            app.Thresh_SegmentButton = uibutton(app.YBranchPlots, 'push');
            app.Thresh_SegmentButton.ButtonPushedFcn = createCallbackFcn(app, @Thresh_SegmentButtonPushed, true);
            app.Thresh_SegmentButton.Text = 'Segment';
            app.ROIWeightpixelsonlyusedforrectsplineLabel = uilabel(app.YBranchPlots);
            app.ROIWeightpixelsonlyusedforrectsplineLabel.Text = {'ROI Weight (pixels)'; ''; ''; '*only used for'; 'rect. & spline'};
            app.ROIWeightSpinner = uispinner(app.YBranchPlots);
            app.ROIWeightSpinner.Step = 0.1;
            app.ROIWeightSpinner.Limits = [1 Inf];
            app.ROIWeightSpinner.Value = 5;
            app.BranchSelectDropDownLabel = uilabel(app.YBranchPlots);
            app.BranchSelectDropDownLabel.Text = 'Branch Select';
            app.BranchSelectDropDown = uidropdown(app.YBranchPlots);
            app.BranchSelectDropDown.Items = {'Select'};
            app.BranchSelectDropDown.ValueChangedFcn = createCallbackFcn(app, @BranchSelectDropDownValueChanged, true);
            app.BranchSelectDropDown.Value = 'Select';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dendrite_branch_decoder_v1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end