classdef ybranchROI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        FileMenu                    matlab.ui.container.Menu
        OpenMenu                    matlab.ui.container.Menu
        OpenNewMenu                 matlab.ui.container.Menu
        ReloadCurrentMenu           matlab.ui.container.Menu
        ExitMenu                    matlab.ui.container.Menu
        HelpMenu                    matlab.ui.container.Menu
        GridLayout                  matlab.ui.container.GridLayout
        StackIn                     matlab.ui.container.Panel
        ROISelectDropDown           matlab.ui.control.DropDown
        ROISelectDropDownLabel      matlab.ui.control.Label
        DrawnROIsSwitch             matlab.ui.control.Switch
        DrawnROIsLabel              matlab.ui.control.Label
        ColormapEditLabel           matlab.ui.control.Label
        ColormapMaxEditField        matlab.ui.control.NumericEditField
        MaxEditFieldLabel           matlab.ui.control.Label
        ColormapMinEditField        matlab.ui.control.NumericEditField
        MinEditFieldLabel           matlab.ui.control.Label
        FrameSelect_Spinner         matlab.ui.control.Spinner
        SS_DeleteButton             matlab.ui.control.Button
        SS_PushButton               matlab.ui.control.Button
        Proj_WindowSizeEditField    matlab.ui.control.NumericEditField
        WindowSizeframesLabel       matlab.ui.control.Label
        Proj_ButtonGroup            matlab.ui.container.ButtonGroup
        Proj_AvgButton              matlab.ui.control.RadioButton
        Proj_VarButton              matlab.ui.control.RadioButton
        Proj_MaxButton              matlab.ui.control.RadioButton
        Proj_NoneButton             matlab.ui.control.RadioButton
        SS_DrawNewButton            matlab.ui.control.Button
        FrameSelect_Slider          matlab.ui.control.Slider
        FrameSelectLabel            matlab.ui.control.Label
        SS_label                    matlab.ui.control.Label
        InputImage                  matlab.ui.control.UIAxes
        YBranchPlots                matlab.ui.container.Panel
        Thresh_SetThresholdSpinner  matlab.ui.control.Spinner
        TC_RefreshButton            matlab.ui.control.Button
        TC_ExitButton               matlab.ui.control.Button
        TC_SaveAsButton             matlab.ui.control.Button
        TC_SyncROIsButton           matlab.ui.control.Button
        ManualCentroidCheckBox      matlab.ui.control.CheckBox
        SplineSmoothSpinner         matlab.ui.control.Spinner
        SplineSmoothnesscontoursplineonlyLabel  matlab.ui.control.Label
        ROITypeDropDownLabel        matlab.ui.control.Label
        ROITypeSelectDropDown       matlab.ui.control.DropDown
        Thresh_EditButton           matlab.ui.control.Button
        BranchSelectDropDown        matlab.ui.control.DropDown
        ROIWeightSpinner            matlab.ui.control.Spinner
        ROIWeightpixelsrectsplineonlyLabel  matlab.ui.control.Label
        Thresh_SegmentButton        matlab.ui.control.Button
        Thresh_DrawNewButton        matlab.ui.control.Button
        Thresh_SetThresholdSlider   matlab.ui.control.Slider
        SetThresholdSliderLabel     matlab.ui.control.Label
        ThreshAndSeg_Label          matlab.ui.control.Label
        TimecoursesPanel            matlab.ui.container.Panel
        TC_Plot_Distal2             matlab.ui.control.UIAxes
        TC_Plot_Distal1             matlab.ui.control.UIAxes
        TC_Plot_Proximal            matlab.ui.control.UIAxes
        YBranchSegment_Plot         matlab.ui.control.UIAxes
        YBranchCropped_Plot         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    % Creator-defined properties, shared variables
    properties (Access = private)
        
        % input image stack
        current_file        = [];           % Currently loaded file name
        img                 = [];           % image stack for <current_file>
        
        % output directory
        out_dir             = [];           % directory where outputs should be written (user selects folder, and data gets written into a folder named <selection>/ybranch)
        
        % handle collector for plots
        Handles             = [];           % structure containing all the plot handles
        
        % input stack: projection
        proj_type           = 'none';       % string type of projeciton to use for plotting input image; default = 'none'
        proj_window_size    = 100;          % window size used for plotting input image; default = 100
        substack            = [];           % currently selected substack (based on app.frame_start & app.proj_window_size)
        curr_proj           = [];           % currently plotted image projection (based on app.substack & app.proj_type)
        
        % input stack: slider
        frame_start         = 1;            % first frame to plot for input image; deafult = 1
        
        % input stack: colormap min/max
        cmap_min            = 0;            % minimum for colormap; default = 0
        cmap_max            = 5000;         % maximum for colormap; default = 5000
        
        % input stack: rois on input fov 
        fov_rois            = [];           % data structure that collects user drawn ROI first, then updates based on thresholding/segmentation; these get plotted on the input stack for user reference
        fov_rois_counter    = 0;            % counter that indicates the current ROI #
        new_draw_resolved   = [];           % logical indicator for whether currently drawn ROI has been 'marked complete', otherwise warns user before preceeding
        
        % dendrite y-branch data structure
        ybranch                 = [];       % data structure that collects y-branch data (masks, splines, angles, timecourses) after using drawing
        ybranch_mask_counter    = [];       % keeps count of drawn y-branch masks for current app.fov_rois_counter
        
        % y-branch threshold
        thresh_val          = [];           % threshold value from slider, default = 1
        thresh_lim          = [];           % threshold limits from slider, default = 1:1000
        
        % segmented branch colormap (proximal, distal1, distal2)
        branchseg_cmap      = [0, 0, 0; .9, .1, .2; .8, .8, 0; 0, .7, .9]; 
        
    end
        
    % Creator-defined functions
    methods (Access = private)
        
        % Image Stack Loader given an input name (uses ScanImageTiffReader.m)
        function [img] = load_tif_stack(app, fin) 
            which ScanImageTiffReader
            % load image stack
            disp(['[' datestr(now) ']: Loading image stack...']);
            img_in = ScanImageTiffReader.ScanImageTiffReader(fin);
            
            % preprocess
            disp(['[' datestr(now) ']: Correcting FOV orientation...']);
            img = img_in.data;  % img_in is flipped L->R than rotated 90 from raw, readjusted in next line.
            img = rot90(img(:, size(img, 2):-1:1, :));
            hdrs = cellstr(splitlines(img_in.descriptions));
            
            % find integer value that corresponds to 0 from ScanImage header, apply normalization to stack so values respect 0.
            disp(['[' datestr(now) ']: Re-zeroing...']);
            offsets = str2num(cell2mat(strrep(hdrs(contains(hdrs, 'c0=')), 'c0=', '')));
            for i = 1:size(img, 3)
                img(:, :, i) = img(:, :, i) + offsets(i);
            end
            
            % set frame-selction slider limits so max reflects last frame of input stack
            app.FrameSelect_Slider.Limits = [1, size(img, 3)];
            
        end
        
        % Plot stack
        function plot_stack(app)
            
            % select sub-stack for plotting
            if app.frame_start > size(app.img, 3) - app.proj_window_size % last frames within window-size just include final window
                app.substack = app.img(:, :, (size(app.img, 3) - app.proj_window_size):end);
            else
                app.substack = app.img(:, :, app.frame_start:(app.frame_start + app.proj_window_size));
            end
            
            % run projection based on type, window, and frame select
            switch app.proj_type
                case 'none'
                    app.curr_proj = app.substack(:, :, 1);
                case 'mean'
                    app.curr_proj = mean(app.substack, 3);
                case 'max'
                    app.curr_proj = max(app.substack, [], 3); 
                case 'var'
                    app.curr_proj = var(single(app.substack), [], 3);
            end
            
            % plot current substack/projection
            app.Handles.InputImage = imagesc(app.InputImage, app.curr_proj); 
            hold(app.InputImage, 'on');
            axis(app.InputImage, [1, size(app.curr_proj, 1), 1, size(app.curr_proj, 2)]);
            
            % colormap
            cmap_instack(app, app.InputImage);
            
            % plot shaded ROIs over current projection, dependent on drawnrois switch
            % TODO
            
            
        end
        
        % Edit colormap for input stack or y-branch threshold - 1000-point greyscale
        function cmap_instack(app, uifig_id)
            
            % adjust colormap according to user-specified limits
            colormap(uifig_id, [linspace(0, 1, 10000)', linspace(0, 1, 10000)', linspace(0, 1, 10000)']);
            caxis(uifig_id, [app.cmap_min, app.cmap_max]);
            
        end
        
        % Edit colormap for input stack or y-branch threshold - 1000-point greyscale
        function cmap_thresh(app, uifig_id)

            % adjust colormap according to user-specified limits
            colormap(uifig_id, [linspace(0, 1, 100000)', linspace(0, 1, 100000)', linspace(0, 1, 100000)']);
            caxis(uifig_id, [app.cmap_min, app.cmap_max]);
            
        end
        
        % Plot cropped ybranch 
        function plot_ybranch_crop(app)
            
            % get current mask padded to fit in a square with some empty space around it
            mask = app.ybranch.rois(app.fov_rois_counter).cropped.fullbranch.mask_nopad;
            proj = app.ybranch.rois(app.fov_rois_counter).cropped.fullbranch.mask_proj_nopad;
            branch_data = app.ybranch.rois(app.fov_rois_counter).cropped.fullbranch.mask_data_nopad;
            
            % add padding to cropped mask/projection/data
            major_pad = 5;
            majdim = find(size(mask) == max(size(mask)), 1);
            mask_padded = zeros(size(mask, majdim) + (2 * major_pad));
            proj_padded = zeros(size(mask, majdim) + (2 * major_pad));
            data_padded   = zeros(size(mask, majdim) + (2 * major_pad), size(mask, majdim) + (2 * major_pad), size(branch_data, 3));
            mindim = abs(majdim - 3);
            minor_pad = (size(mask_padded, 1) - size(mask, mindim)) / 2;
            if majdim == 1
                mask_padded((major_pad + 1):(major_pad + size(mask, 1)), (minor_pad + 1):(minor_pad + size(mask, 2))) = mask;
                proj_padded((major_pad + 1):(major_pad + size(mask, 1)), (minor_pad + 1):(minor_pad + size(mask, 2))) = proj;
                data_padded((major_pad + 1):(major_pad + size(mask, 1)), (minor_pad + 1):(minor_pad + size(mask, 2)), :) = branch_data;
            else
                mask_padded((minor_pad + 1):(minor_pad + size(mask, 1)), (major_pad + 1):(major_pad + size(mask, 2))) = mask;
                proj_padded((minor_pad + 1):(minor_pad + size(mask, 1)), (major_pad + 1):(major_pad + size(mask, 2))) = proj;
                data_padded((minor_pad + 1):(minor_pad + size(mask, 1)), (major_pad + 1):(major_pad + size(mask, 2)), :) = branch_data;
            end
            
            % sink cropped data to ybranch structure
            app.ybranch.rois(app.fov_rois_counter).cropped.majordim_padsz       = major_pad;    % padding size used for turning mask into padded mask
            app.ybranch.rois(app.fov_rois_counter).cropped.fullbranch.mask      = mask_padded;  % full branch mask
            app.ybranch.rois(app.fov_rois_counter).cropped.fullbranch.mask_proj = proj_padded;  % projection in full branch mask
            app.ybranch.rois(app.fov_rois_counter).cropped.fullbranch.mask_data = data_padded;  % 3D data in full branch mask
            
            % plot projection in padded mask
            mp = imagesc(app.YBranchCropped_Plot, proj_padded, 'AlphaData', (logical(proj_padded) .* (1)) );
            set(app.YBranchCropped_Plot, 'Color', 'k', 'visible', 'on');
            axis(app.YBranchCropped_Plot, 'tight');
            hold(app.YBranchCropped_Plot, 'on');
            
            % recolor
            cmap_thresh(app, app.YBranchCropped_Plot);
            
            % plot individual component branch masks if present
            if isfield(app.ybranch.rois(1).cropped, 'components')
                plot_cropped_masks(app);
            end
            
        end
        
        % plot branch segment masks on cropped Y-branch mask
        function plot_cropped_masks(app)
            
            cropped = app.ybranch.rois(app.fov_rois_counter).cropped;
            bnames = {'branch1', 'branch2', 'branch3', 'distal1', 'distal2', 'proximal'};
            
            for i = 1:size(bnames, 2)
                                
                bname = bnames{i};
                
                % plot branch component if present
                if isfield(cropped.components, bname)
                    
                    if app.ybranch.rois(app.fov_rois_counter).segmented.status == 0
                        
                        % get current mask from structure
                        eval(['bmask = cropped.components.' bname '.mask;']);
                        eval(['bhandle = cropped.components.' bname '.mask_handle;']);
                        mask_proj  = cropped.fullbranch.mask_proj; 
                        bmask_proj = (bmask .* mask_proj);
                        
                        % threshold value for selecting dendritic branch (threshold is fraction relative to max signal in current Y'branch's full projection)
                        thresh      = eval(['app.ybranch.rois(app.fov_rois_counter).cropped.components.' bname '.thresh;']);
                        
                        % gaussian filter
                        projsmooth  = imgaussfilt(mask_proj, 2, 'FilterSize', 3, 'Padding', 'replicate', 'FilterDomain', 'spatial');    % smoothed full Y-branch
                        bprojsmooth = imgaussfilt(bmask_proj, 2, 'FilterSize', 3, 'Padding', 'replicate', 'FilterDomain', 'spatial');   % smoothed Y-branch segment
                        
                        % extract dendrite from background
                        bw_extract          = bwlabel(bprojsmooth > (thresh * max(max(projsmooth))));       % extract connected components greater than threshold (threshold based on values in curent masked & smoothed projection only)
                        bw_extract_filled   = imfill(bw_extract, 'holes');                                  % fill holes in bw_extract
                        bw_extract_tab      = tabulate(bw_extract_filled(:));                               % tabulate size of connected components
                        subbranchmask       = (bw_extract_filled == (find(bw_extract_tab(2:end, 2) == max(bw_extract_tab(2:end, 2)))));     % take largest connected component (assumed to be the branch)
                        
                        % output the thresholded subbranchmask to data structure
                        eval(['app.ybranch.rois(app.fov_rois_counter).cropped.components.' bname '.mask_thresh = subbranchmask;']);
                        
                    else
                        
                        % get already drawn subbranchmask from data structure
                        % -- WORKING
                        eval(['subbranchmask = app.ybranch.rois(app.fov_rois_counter).cropped.components.' bname '.mask_thresh;']);
                        eval(['draw_position = app.ybranch.rois(app.fov_rois_counter).cropped.components.' bname '.drawn_mask.Position;']);
                        eval(['draw_label = app.ybranch.rois(app.fov_rois_counter).cropped.components.' bname '.drawn_mask.Label;']);
                        
                        
                    end
                    
                    % plot the subbranch mask on top of the cropped projection
                    oax = app.YBranchCropped_Plot;
                    sbm = imagesc(oax, -1 * subbranchmask, 'AlphaData', (logical(subbranchmask) .* (3/5)) );
                    
                    % scale colormap by min and max value in current projection
                    set(oax, 'Colormap', [1, 0, 0; 0, 0, 0; linspace(0, 1, range(mask_proj, 'all'))', linspace(0, 1, range(mask_proj, 'all'))', linspace(0, 1, range(mask_proj, 'all'))']);
                                        
                end
                
            end
            
        end
        
        % Plot segmented ybranch 
        function plot_ybranch_segment(app)
            
            % clear plot
            cla(app.YBranchSegment_Plot);
            text(app.YBranchSegment_Plot, .25, .5, 'Loading Segmentation...', 'FontSize', 20)
            pause(.1)
            % ybranch necessary pieces
            cmp = app.ybranch.rois(app.fov_rois_counter).cropped.components;
            if ~isempty(cmp.branch1) && ~isempty(cmp.branch2) && ~isempty(cmp.branch3)
                b1          = cmp.branch1.mask_thresh;
                b2          = cmp.branch2.mask_thresh;
                b3          = cmp.branch3.mask_thresh;
                ball        = (b1 + b2 + b3);
            elseif ~isempty(cmp.proximal) && ~isempty(cmp.distal1) && ~isempty(cmp.distal2)
                b1          = cmp.proximal.mask_thresh;
                b2          = cmp.distal1.mask_thresh;
                b3          = cmp.distal2.mask_thresh;
                ball        = (b1 + b2 + b3);
            else
                % Warn user that all the masks don't seem to be in place
                msgbox({'Yikes!'; 'Sorry, it seems like you haven''t drawn 3 masks here yet...'}, 'Warning', 'warn');
            end
            
            if ~isempty(ball)
                
                % separate Y-branch segments
                all_masks       = (1 * bwlabel(b1)) + (2 * bwlabel(b2)) + (3 * bwlabel(b3));
                
                % now start plotting
                cla(app.YBranchSegment_Plot);
                
                % sync data to ybranch structure & write angles
                app.ybranch.rois(app.fov_rois_counter).segmented = ybranch_get_angles(app, all_masks); % branches now named according to proximal/distal
                
                % plot thresholded masks & fit splines TODO MASK SELECTOR
                bnames = {'proximal', 'distal1', 'distal2'};
                for i = 1:size(bnames, 2)
                                    
                    % get branch comparison data for current branch
                    mtype       = eval(['app.ybranch.rois(app.fov_rois_counter).segmented.plot_roi_type;']);
                    bmask       = eval(['app.ybranch.rois(app.fov_rois_counter).segmented.' bnames{i} '.mask_' mtype]);
                    spline_fit  = eval(['app.ybranch.rois(app.fov_rois_counter).segmented.' bnames{i} '.spline_fit']);
                    
                    % plotter
                    mm = imagesc(app.YBranchSegment_Plot, (bmask .* i)); 
                    hold(app.YBranchSegment_Plot, 'on');
                    set(mm, 'AlphaData', (logical(bmask) .* (4/5)) );
                    plot(app.YBranchSegment_Plot, spline_fit(:, 1), spline_fit(:, 2), 'k', 'LineWidth', 2);
                    
                    % clean up
                    clear bmask spline_fit mm
                    
                end
                
                % plot midpoint
                scatter(app.YBranchSegment_Plot, app.ybranch.rois(app.fov_rois_counter).segmented.centroid(1), app.ybranch.rois(app.fov_rois_counter).segmented.centroid(2), 100, 'k');
                app.Handles.centroid_draw = drawcircle(app.YBranchSegment_Plot, 'Center', [app.ybranch.rois(app.fov_rois_counter).segmented.centroid], 'Radius', 8, 'FaceAlpha', 0, 'SelectedColor', [.7, .7, .7], 'Selected', 1, 'LineWidth', 1.5, 'InteractionsAllowed', 'translate'); 
                app.Handles.centroid_coordinate = app.Handles.centroid_draw.Center;
                
                % some axis properties
                colormap(app.YBranchSegment_Plot, app.branchseg_cmap);
                axis(app.YBranchSegment_Plot, 'tight');
                
            end

        end
        
        % Calculate splines for Y-branch segments --- split this up into separate functions?? TODO
        function [bcomp] = ybranch_fit_splines(app, all_masks)
            
            % initialize bcompare structure
            bcomp = []; 
            bcomp.masks_all = all_masks;
            
            % get settings
            bcomp.roi_weight    = eval(['app.ybranch.rois(app.fov_rois_counter).segmented.roi_weight;']);
            bcomp.spline_smooth = eval(['app.ybranch.rois(app.fov_rois_counter).segmented.spline_smooth;']);
            bcomp.plot_roi_type = eval(['app.ybranch.rois(app.fov_rois_counter).segmented.plot_roi_type;']);
            if isfield(eval(['app.ybranch.rois(app.fov_rois_counter).segmented']), 'centroid_method')
                bcomp.centroid_method = eval(['app.ybranch.rois(app.fov_rois_counter).segmented.centroid_method;']);
            else
                bcomp.centroid_method = 'auto';
            end
            
            % loop through all_masks, fitting a path of centroids within each mask
            for i = 1:size(unique(bcomp.masks_all(bcomp.masks_all > 0)), 1)
                
                % current mask name (temp until angles can be compared)
                bname = eval(['''branch' num2str(i) '''']);
            
                % current mask & orientation
                bmask_contour = (bcomp.masks_all == i);
                bm_orient = regionprops(bmask_contour, 'Orientation').Orientation;
                
                % get coordinates in box and fit split, append branch point centroid
                [yc, xc]    = find(bmask_contour);     % all points within branch
            
                % find orientation of bmask's major axis;
                %   - this is used to determine if a line of centroids through the mask 
                %   should be calculated by successive cuts in the x-dimension 
                %   (2 in matrix notation) or y-dimension (1 in matrix notation).
                %   - calculate the centroids: bm_centpts = mask's (x, y) centroids
                if abs(bm_orient) >=45 % orientation is bounded on the range [-90, 90]
                    % mask is vertical, so use y-dimension cuts
                    bm_centpts = [zeros(size(unique(yc), 1), 1), unique(yc)];
                    for j = 1:size(bm_centpts, 1)
                        bm_centpts(j, 1) = mean(xc(yc == bm_centpts(j, 2)));
                    end
                else
                    % mask is horizontal, so use x-dimension cuts
                    bm_centpts = [unique(xc), zeros(size(unique(xc), 1), 1)];
                    for j = 1:size(bm_centpts, 1)
                        bm_centpts(j, 2) = mean(yc(xc == bm_centpts(j, 1)));
                    end
                end
                
                % output coordinates of mask path (line of centroids through the mask)
                eval(['bcomp.' bname '.mask_path = bm_centpts;']);
                
            end
            
            % find intersection nearest points on pairs of each Y-branch mask,
            % then use coords to triangulate putative center of the Y-Branch
            if strcmp(bcomp.centroid_method, 'auto')
                bps = [];
                for i = 1:size(unique(bcomp.masks_all(bcomp.masks_all > 0)), 1)
                    for j = 1:size(unique(bcomp.masks_all(bcomp.masks_all > 0)), 1)
                        if i ~= j
                            path_dist = [];
                            p1 = eval(['bcomp.branch' num2str(i) '.mask_path']);
                            p2 = eval(['bcomp.branch' num2str(j) '.mask_path']);
                            for m = 1:size(p1, 1)
                                for n = 1:size(p2, 1)
                                    path_dist(m, n) = pdist([p1(m, :); p2(n, :)], 'euclidean');
                                end
                            end
                            [~, ylook] = find(path_dist == min(min(path_dist)));
                            bps = [bps; p2(ylook, :)];
                        end
                    end
                end
                bcomp.centroid = mean(bps, 1);
            elseif strcmp(bcomp.centroid_method, 'manual')
                if isfield(app.Handles, 'centroid_coordinate')
                    bcomp.centroid = app.Handles.centroid_coordinate;
                else
                    bcomp.centroid = eval(['app.ybranch.rois(app.fov_rois_counter).segmented.centroid;']);
                end
            end
            
            % loop through all_masks, fitting actual smoothing spline
            for i = 1:size(unique(bcomp.masks_all(bcomp.masks_all > 0)), 1)
            
                % current mask name (temp until angles can be compared)
                bname = eval(['''branch' num2str(i) '''']);
            
                % current mask (contour)
                bmask_contour = (bcomp.masks_all == i);
            
                % get coordinates in box and fit split, append branch point centroid
                [yc, xc]    = find(bmask_contour);     % all points within branch
                
                % add y-branch centroid to current mask's path for fitting a spline
                xc = [xc; bcomp.centroid(1)];
                yc = [yc; bcomp.centroid(2)];
                
                % fit spline to x-y data for branch
                bm_orient = regionprops(bmask_contour, 'Orientation').Orientation;
                bm_centpts = [];
                spline_fit = [];
                sf_nocent = [];
                if abs(bm_orient) >=45 % orientation is bounded on the range [-90, 90]
                    % mask is vertical, so use y-dimension cuts
                    bm_centpts = [zeros(size(unique(yc), 1), 1), unique(yc)];
                    for j = 1:size(bm_centpts, 1)
                        bm_centpts(j, 1) = mean(xc(yc == bm_centpts(j, 2)));
                    end
                    pp = smooth(bm_centpts(:, 2), bm_centpts(:, 1), bcomp.spline_smooth, 'rloess');
                    pl = polyval(polyfit(bm_centpts(bm_centpts(:, 2) ~= bcomp.centroid(2), 2), bm_centpts(bm_centpts(:, 1) ~= bcomp.centroid(1), 1), 1), bm_centpts(bm_centpts(:, 2) ~= bcomp.centroid(2), 2));
                    lin_fit = [pl, bm_centpts(bm_centpts(:, 2) ~= bcomp.centroid(2), 2)];
                    spline_fit = [pp, bm_centpts(:, 2)];
                    sf_nocent = spline_fit(spline_fit(:, 2) ~= bcomp.centroid(2), :);
                else
                    % mask is horizontal, so use x-dimension cuts
                    bm_centpts = [unique(xc), zeros(size(unique(xc), 1), 1)];
                    for j = 1:size(bm_centpts, 1)
                        bm_centpts(j, 2) = mean(yc(xc == bm_centpts(j, 1)));
                    end
                    pp = smooth(bm_centpts(:, 1), bm_centpts(:, 2), bcomp.spline_smooth, 'rloess');
                    pl = polyval(polyfit(bm_centpts(bm_centpts(:, 1) ~= bcomp.centroid(1), 1), bm_centpts(bm_centpts(:, 2) ~= bcomp.centroid(2), 2), 1), bm_centpts(bm_centpts(:, 1) ~= bcomp.centroid(1), 1));
                    lin_fit = [bm_centpts(bm_centpts(:, 1) ~= bcomp.centroid(1), 1), pl];
                    spline_fit = [bm_centpts(:, 1), pp];
                    sf_nocent = spline_fit(spline_fit(:, 1) ~= bcomp.centroid(1), :);
                end
                
                % rectangular mask
                theta = []; center = []; width = []; height = []; coords = []; R = []; rot_coords = []; 
                theta = bm_orient * (pi/180);
                center = [(lin_fit(1, 1)+lin_fit(end, 1))/2, (lin_fit(1, 2)+lin_fit(end, 2))/2]';
                width = sqrt((lin_fit(1, 1) - lin_fit(end, 1))^2 + (lin_fit(1, 2) - lin_fit(end, 2))^2);
                height = bcomp.roi_weight;
                coords = [center(1)-(width/2) center(1)-(width/2) center(1)+(width/2)  center(1)+(width/2);...
                          center(2)-(height/2) center(2)+(height/2) center(2)+(height/2)  center(2)-(height/2)];
                R = [cos(theta) sin(theta);...
                    -sin(theta) cos(theta)];
                rot_coords = R * (coords-repmat(center,[1 4])) + repmat(center,[1 4]);
                bmask_rect = poly2mask(rot_coords(1, :), rot_coords(2, :), size(app.ybranch.rois(app.fov_rois_counter).cropped.fullbranch.mask, 1), size(app.ybranch.rois(app.fov_rois_counter).cropped.fullbranch.mask, 2));
                
                % spline-shaped mask
                perp_theta = []; coords = []; R = [];
                perp_theta = (bm_orient - 90) * (pi/180);
                R = [cos(perp_theta) sin(perp_theta);...
                    -sin(perp_theta) cos(perp_theta)];
                s1 = []; s1r = []; s2 = []; s2r = [];
                for j = 1:size(sf_nocent, 1)
                    s1(j, :)    = [sf_nocent(j, 1) + bcomp.roi_weight/2, sf_nocent(j, 2)];
                    t1          = R * ([s1(j, :); sf_nocent(j, :)] - repmat(sf_nocent(j, :), 2, 1)) + sf_nocent(j, :)';
                    s1r(j, :)   = t1(:, 1);
                    s2(j, :)    = [sf_nocent(j, 1) - bcomp.roi_weight/2, sf_nocent(j, 2)];
                    t2          = R * ([s2(j, :); sf_nocent(j, :)] - repmat(sf_nocent(j, :), 2, 1)) + sf_nocent(j, :)';
                    s2r(j, :)   = t2(:, 1);
                end
                coords = [s1r; s2r(end:-1:1, :)]';
                bmask_spline = poly2mask(coords(1, :), coords(2, :), size(app.ybranch.rois(app.fov_rois_counter).cropped.fullbranch.mask, 1), size(app.ybranch.rois(app.fov_rois_counter).cropped.fullbranch.mask, 2));
                
                % sink values
                eval(['bcomp.' bname '.mask_contour = bmask_contour;']);
                eval(['bcomp.' bname '.mask_rectangle = bmask_rect;']);
                eval(['bcomp.' bname '.mask_spline = bmask_spline;']);
                eval(['bcomp.' bname '.mask = bcomp.' bname '.mask_' bcomp.plot_roi_type ';']);
                eval(['bcomp.' bname '.spline_fit = spline_fit;']);
            
                % clean up
                clear bname bmask spline_fit yc xc pp py mm
            
            end
            
        end
        
        % get angles between splines
        function [bcomp] = ybranch_get_angles(app, all_masks)
            
            % fit splines
            bcomp   = ybranch_fit_splines(app, all_masks);
            
            % set a status marker that will turn true when ROIs are sync'ed
            bcomp.status = 0;
            
            % calculate angle between each spline, determine proximal (relative to soma) vs. two distal components
            fnames = fieldnames(bcomp);
            bnames = fnames(contains(fnames, 'branch')); % branches named arbitrarily 1, 2, 3
            offset = 4; % points to offset from branch point -- could use zero but slightly odd behavior for branch components whose splines that do not converge on branch point
            for i = 1:size(bnames, 1)
                
                % get last point of spline (separated by offset) leading up to branch point center
                nb1 = setdiff(eval(['bcomp.' bnames{i} '.spline_fit']), bcomp.centroid, 'rows');
                if pdist([nb1(end, :)', bcomp.centroid']') < pdist([nb1(1, :)', bcomp.centroid']')
                    nb1 = nb1;
                else
                    nb1 = nb1(end:-1:1, :);
                end
                x1 = nb1(end - offset, 1);
                y1 = nb1(end - offset, 2);
                nb1_edge = nb1(1, :);
                
                for j = (i+1):size(bnames, 1)
                    
                    % get last point of spline (separated by offset) leading up to branch point center
                    nb2 = setdiff(eval(['bcomp.' bnames{j} '.spline_fit']), bcomp.centroid, 'rows');
                    if pdist([nb2(end, :)', bcomp.centroid']') < pdist([nb2(1, :)', bcomp.centroid']')
                        nb2 = nb2;
                    else
                        nb2 = nb2(end:-1:1, :);
                    end
                    x2 = nb2(end - offset, 1);
                    y2 = nb2(end - offset, 2);
                    nb2_edge = nb2(1, :);
                    
                    % calculate angle
                    v1 = [x1, y1, 0] - [bcomp.centroid(:, 1), bcomp.centroid(:, 2), 0];         % segment to centroid
                    v2 = [x2, y2, 0] - [bcomp.centroid(:, 1), bcomp.centroid(:, 2), 0];         % segment to centroid
                    bcomp.thetas_unsorted(i, j) = atan2(norm(cross(v1, v2)), dot(v1, v2));    % radian difference
                    bcomp.angles_unsorted(i, j) = rad2deg(bcomp.thetas_unsorted(i, j));     % convert to degrees
                    
                    % write angle onto plot between mask centroids
                    if i ~= j
                        % get position of longest difference between x & y, use this and longest other dimension away from centroid as writing location
                        if abs(nb1_edge(1) - nb2_edge(1)) > abs(nb1_edge(2) - nb2_edge(2)) 
                            tx = (nb1_edge(1) + nb2_edge(1)) / 2;
                            if abs(nb1_edge(2) - bcomp.centroid(2)) > abs(nb2_edge(2) - bcomp.centroid(2))
                                ty = nb1_edge(2);
                            else
                                ty = nb2_edge(2);
                            end
                        else
                            ty = (nb1_edge(2) + nb2_edge(2)) / 2;
                            if abs(nb1_edge(1) - bcomp.centroid(1)) > abs(nb2_edge(1) - bcomp.centroid(1))
                                tx = nb1_edge(1);
                            else
                                tx = nb2_edge(1);
                            end
                        end
                        txdist = 40;
                        txy = bcomp.centroid + (([tx, ty] - bcomp.centroid) * (txdist / norm([tx, ty] - bcomp.centroid)));
                        text(app.YBranchSegment_Plot, txy(1), txy(2), [sprintf('%.2f', bcomp.angles_unsorted(i, j)) '' char(176)], 'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle', 'FontSize', 14);
                    end
                    
                end
                
            end
            
            % write index of proximal/distal to structure
            ang_vec = bcomp.angles_unsorted(logical(triu(bcomp.angles_unsorted, 1))); % 1->2; 1->3; 2->3;
            if min(ang_vec) == ang_vec(1)
                bcomp.proximal = bcomp.branch3;
                if sort(ang_vec(~(ang_vec == min(ang_vec)))) == ang_vec(~(ang_vec == min(ang_vec)))
                    bcomp.distal1 = bcomp.branch2;
                    bcomp.distal2 = bcomp.branch1;
                else
                    bcomp.distal1 = bcomp.branch1;
                    bcomp.distal2 = bcomp.branch2;
                end
            elseif min(ang_vec) == ang_vec(2)
                bcomp.proximal = bcomp.branch2;
                if sort(ang_vec(~(ang_vec == min(ang_vec)))) == ang_vec(~(ang_vec == min(ang_vec)))
                    bcomp.distal1 = bcomp.branch3;
                    bcomp.distal2 = bcomp.branch1;
                else
                    bcomp.distal1 = bcomp.branch1;
                    bcomp.distal2 = bcomp.branch3;
                end
            elseif min(ang_vec) == ang_vec(3)
                bcomp.proximal = bcomp.branch1;
                if sort(ang_vec(~(ang_vec == min(ang_vec)))) == ang_vec(~(ang_vec == min(ang_vec)))
                    bcomp.distal1 = bcomp.branch3;
                    bcomp.distal2 = bcomp.branch2;
                else
                    bcomp.distal1 = bcomp.branch2;
                    bcomp.distal2 = bcomp.branch3;
                end
            end
            
        end
        
        % plot timecourses of segmented y-branch
        function plot_ybranch_timecourses(app)
            
            % get data from properties
            dat = app.ybranch.rois(app.fov_rois_counter).cropped.fullbranch.mask_data;
            seg = app.ybranch.rois(app.fov_rois_counter).segmented;
            
            % calculate time courses
            tc_prox = mean((reshape(dat, [size(dat, 1) * size(dat, 2), size(dat, 3)]) .* reshape(seg.proximal.mask, [size(dat, 1) * size(dat, 2), 1])), 1);
            tc_dist1 = mean((reshape(dat, [size(dat, 1) * size(dat, 2), size(dat, 3)]) .* reshape(seg.distal1.mask, [size(dat, 1) * size(dat, 2), 1])), 1);
            tc_dist2 = mean((reshape(dat, [size(dat, 1) * size(dat, 2), size(dat, 3)]) .* reshape(seg.distal2.mask, [size(dat, 1) * size(dat, 2), 1])), 1);
            
            % plot timecourses
            app.Handles.TC_prox  = plot(app.TC_Plot_Proximal, tc_prox, 'Color', app.branchseg_cmap(2, :), 'LineWidth', 2);
            app.Handles.TC_dist1 = plot(app.TC_Plot_Distal1, tc_dist1, 'Color', app.branchseg_cmap(3, :), 'LineWidth', 2);
            app.Handles.TC_dist2 = plot(app.TC_Plot_Distal2, tc_dist2, 'Color', app.branchseg_cmap(4, :), 'LineWidth', 2);
            
            % axis limits dependent on group max/min
            xmax = size(tc_prox, 2);
            ymax = max(max([tc_prox; tc_dist1; tc_dist2]));
            axis(app.TC_Plot_Proximal, [0, xmax, 0, 1.1 * ymax]);
            axis(app.TC_Plot_Distal1, [0, xmax, 0, 1.1 * ymax]);
            axis(app.TC_Plot_Distal2, [0, xmax, 0, 1.1 * ymax]);
            
            % lableling extras
            ll(1) = legend(app.TC_Plot_Proximal, 'proximal'); 
            ll(2) = legend(app.TC_Plot_Distal1, 'distal1'); 
            ll(3) = legend(app.TC_Plot_Distal2, 'distal2'); 
            set(ll, 'Box', 'off');
            
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: OpenNewMenu
        function OpenNewMenuSelected(app, event)
            
            % get file
            [fn, fp] = uigetfile('*.tif*');
            app.current_file = fullfile(fp, fn);
            if isequal(app.current_file, 0)
               disp(['[' datestr(now) ']: User canceled file selection...']);
            else
               disp(['[' datestr(now) ']: Loading data file <' app.current_file '>...']);
               app.img = load_tif_stack(app, app.current_file);
            end
            
            if ~isempty(app.current_file)
                % initialize data structure properties of shared scope for the current input file
                app.out_dir = [];
                app.ybranch = [];
                app.fov_rois = [];
                app.fov_rois_counter = 0;
            
                % reset components
                cla(app.InputImage);
                cla(app.YBranchCropped_Plot);
                cla(app.YBranchSegment_Plot);
                cla(app.TC_Plot_Proximal);
                cla(app.TC_Plot_Distal1);
                cla(app.TC_Plot_Distal2);
                app.FrameSelect_Slider.Value = 1;
                FrameSelect_SliderValueChanged(app);
                app.FrameSelect_Spinner.Value = 1;
                FrameSelect_SpinnerValueChanged(app);
                app.Proj_NoneButton.Value = 1;
                Proj_ButtonGroupSelectionChanged(app);
                app.Proj_WindowSizeEditField.Value = 100;
                Proj_WindowSizeEditFieldValueChanged(app);
                app.ROISelectDropDown.Items = {'Select'};
                app.ROISelectDropDown.Value = 'Select';
                ROISelectDropDownValueChanged(app);
                app.BranchSelectDropDown.Items = {'Select Branch'};
                app.BranchSelectDropDown.Value = 'Select Branch';
                BranchSelectDropDownValueChanged(app);
                app.Thresh_SetThresholdSlider.Value = 1;
                Thresh_SetThresholdSliderValueChanged(app);
                app.Thresh_EditButton.Text = 'Edit';
                app.Thresh_EditButton.FontColor = [0, 0, 0];
                app.SS_DrawNewButton.Text       = 'Draw New';
                app.SS_DrawNewButton.FontColor  = [0, 0, 0];
                app.Thresh_SetThresholdSlider.Enable = 1;
                
            end
            
            % put origin filename in ybranch data structure
            app.ybranch.provenance = app.current_file;
            
            % plot input stack
            plot_stack(app);
            
        end

        % Menu selected function: ReloadCurrentMenu
        function ReloadCurrentMenuSelected(app, event)
            
            % reload current file
            if isempty(app.current_file)
                
                % dialog box that no file selected
                msgbox({'No ''current'' input to load!'; 'Try: File -> Open -> Open New'}, 'Error', 'error');
                
            else
                
                mcinput = questdlg({'Warning!'; 'Unsaved progress on ROIs will be lost!'; 'Do you want to proceed?'});
                if strcmp(mcinput, 'Yes')
                
                    disp(['[' datestr(now) ']: Reloading data file <' app.current_file '>...']);
                    app.img = load_tif_stack(app, app.current_file);
                    
                    % initialize data structure properties of shared scope for the current input file
                    app.ybranch = [];
                    app.fov_rois = [];
                    app.fov_rois_counter = 0;
                    
                    % reset components --- may want to change what is reset on reload when replotting same image... (keep ROIs, etc.)
                    cla(app.InputImage);
                    cla(app.YBranchCropped_Plot);
                    cla(app.YBranchSegment_Plot);
                    cla(app.TC_Plot_Proximal);
                    cla(app.TC_Plot_Distal1);
                    cla(app.TC_Plot_Distal2);
                    app.FrameSelect_Slider.Value = 1;
                    FrameSelect_SliderValueChanged(app);
                    app.FrameSelect_Spinner.Value = 1;
                    FrameSelect_SpinnerValueChanged(app);
                    app.Proj_NoneButton.Value = 1;
                    Proj_ButtonGroupSelectionChanged(app);
                    app.Proj_WindowSizeEditField.Value = 100;
                    Proj_WindowSizeEditFieldValueChanged(app);
                    app.ROISelectDropDown.Items = {'Select'};
                    app.ROISelectDropDown.Value = 'Select';
                    ROISelectDropDownValueChanged(app);
                    app.BranchSelectDropDown.Items = {'Select Branch'};
                    app.BranchSelectDropDown.Value = 'Select Branch';
                    BranchSelectDropDownValueChanged(app);
                    app.Thresh_SetThresholdSlider.Value = 1;
                    Thresh_SetThresholdSliderValueChanged(app);
                    app.Thresh_EditButton.Text = 'Edit';
                    app.Thresh_EditButton.FontColor = [0, 0, 0];
                    app.SS_DrawNewButton.Text       = 'Draw New';
                    app.SS_DrawNewButton.FontColor  = [0, 0, 0];
                    app.Thresh_SetThresholdSlider.Enable = 1;
                    
                    % put origin filename in ybranch data structure
                    app.ybranch.provenance = app.current_file;
                    
                    % plot input stack
                    plot_stack(app);
                    
                end

            end
            
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            
            mcinput = questdlg({'Warning!'; 'GUI will shut down and progress on ROIs will be lost!'; 'Do you want to proceed?'});
            if strcmp(mcinput, 'Yes')
                
                % cancel reload if anything but "Yes"
                disp(['[' datestr(now) ']: GUI shutting down...']);
                close(app.UIFigure);
                
            end
            
        end

        % Menu selected function: HelpMenu
        function HelpMenuSelected(app, event)
            
            % Warn user that no help has been created yet
            msgbox('Yikes! Sorry, no help section has been created yet...', 'Warning', 'warn');
            
        end

        % Selection changed function: Proj_ButtonGroup
        function Proj_ButtonGroupSelectionChanged(app, event)
            
            selectedButton = app.Proj_ButtonGroup.SelectedObject;
            
            % command line feedback
            disp(['[' datestr(now) ']: Projection type set to ''' selectedButton.Text '''...']);
            
            % toggle projection type, set default min & max for toggle
            if strcmp(selectedButton.Text, 'None')
                app.proj_type = 'none';
                app.ColormapMinEditField.Value = 0;
                app.ColormapMaxEditField.Value = 1000;
            elseif strcmp(selectedButton.Text, 'Average')
                app.proj_type = 'mean';
                app.ColormapMinEditField.Value = 0;
                app.ColormapMaxEditField.Value = 1000;
            elseif strcmp(selectedButton.Text, 'Maximum')
                app.proj_type = 'max';
                app.ColormapMinEditField.Value = 0;
                app.ColormapMaxEditField.Value = 2500;
            elseif strcmp(selectedButton.Text, 'Variance')
                app.proj_type = 'var';
                app.ColormapMinEditField.Value = 0;
                app.ColormapMaxEditField.Value = 250000;
            end
            
            % colormap
            app.cmap_min = app.ColormapMinEditField.Value;
            app.cmap_max = app.ColormapMaxEditField.Value;
            
            % replot
            plot_stack(app);
            
        end

        % Value changed function: FrameSelect_Slider
        function FrameSelect_SliderValueChanged(app, event)

            % get value on change, set as first frame of projection
            value = app.FrameSelect_Slider.Value;
            app.frame_start = value;
            
            % make value change reciprocal with spinner
            app.FrameSelect_Spinner.Value = value;
            
            % replot
            plot_stack(app);
            
        end

        % Value changed function: FrameSelect_Spinner
        function FrameSelect_SpinnerValueChanged(app, event)
            
            % get value on change, set as first frame of projection
            value = app.FrameSelect_Spinner.Value;
            app.frame_start = value;
            
            % make value reciprocal with slider
            app.FrameSelect_Slider.Value = value;
            
            % replot
            plot_stack(app);
            
        end

        % Value changed function: Proj_WindowSizeEditField
        function Proj_WindowSizeEditFieldValueChanged(app, event)
            
            % get value change for window size
            value = app.Proj_WindowSizeEditField.Value;
            app.proj_window_size = value;
            
            % command line feedback
            disp(['[' datestr(now) ']: Projection window size set to ' num2str(value) '...'])
            
            % replot
            plot_stack(app);
            
        end

        % Value changed function: ColormapMinEditField
        function ColormapMinEditFieldValueChanged(app, event)
            
            % get value on change, set as colormap minimum
            value = app.ColormapMinEditField.Value;
            app.cmap_min = value;
            
            % replot
            plot_stack(app);
            
        end

        % Value changed function: ColormapMaxEditField
        function ColormapMaxEditFieldValueChanged(app, event)
                        
            % get value on change, set as colormap maximum
            value = app.ColormapMaxEditField.Value;
            app.cmap_max = value;
            
            % replot
            plot_stack(app);

        end

        % Button pushed function: SS_DrawNewButton
        function SS_DrawNewButtonPushed(app, event)
            
            bpush = 1;
            while bpush == 1
                
                % sanity check for whether currently drawn ROI has been marked complete
                if app.new_draw_resolved == 0
                    
                    msgbox({'Yikes!'; ['Looks like the last ROI you drew (#' num2str(app.fov_rois_counter) ') did not get PUSHED to thresholder/segmentation...']; 'You will need to PUSH or DELETE it before moving on!'}, 'Warning', 'warn');
                    app.ROISelectDropDown.Value = num2str(app.fov_rois_counter);
                    ROISelectDropDownValueChanged(app);
                    bpush = 0;
                    
                else
                    
                    % change button appearance to reinforce drawing is active
                    app.SS_DrawNewButton.Text       = 'Drawing...';
                    app.SS_DrawNewButton.FontColor  = [.85, 0, 0];
                    
                    % For polygons drawn on FOV, start at 1 or increment depending on already drawn 
                    if isempty(app.fov_rois)
                        app.fov_rois = [];
                        app.fov_rois.init = 1;
                        app.fov_rois_counter = 1;
                        app.new_draw_resolved = 1;
                    else
                        app.fov_rois_counter = (size(app.fov_rois, 2) + 1);     % increment counter to current ROI
                    end
                    
                    % unselect other ROIs
                    app.ROISelectDropDown.Value = 'Select';
                    ROISelectDropDownValueChanged(app);
                    
                    % draw new roi on the full fov
                    fov_rois_opts = [];
                    fov_rois_opts.Label                 = num2str(app.fov_rois_counter);
                    fov_rois_opts.LineWidth             = 1.5; 
                    fov_rois_opts.Selected              = 1;                % automatically select the current drawing
                    fov_rois_opts.SelectedColor         = [1, 1, 0];       % selection color is red
                    fov_rois_opts.Color                 = [0, .5, 1];     % default color is blue
                    fov_rois_opts.InteractionsAllowed   = 'none';           % 'none' will lock ROI to edits
                    fov_rois_opts.FaceSelectable        = 1;
                    app.fov_rois(app.fov_rois_counter).Draw = drawpolygon(app.InputImage, fov_rois_opts);   % mask to sink
                    app.fov_rois(app.fov_rois_counter).init = 1;
                    
                    % Add newly drawn ROI to dropdown & select
                    app.ROISelectDropDown.Items = [app.ROISelectDropDown.Items, num2str(app.fov_rois_counter)];
                    app.ROISelectDropDown.Value = num2str(app.fov_rois_counter);
                    ROISelectDropDownValueChanged(app);
                    
                    % mark drawn unresolved
                    app.new_draw_resolved = 0;          % 0 = new drawing not marked complete, 1 = new drawing marked complete
                
                    % end while condition
                    bpush = 0;
                    
                end
                
            end
            
            % reset Draw New button
            app.SS_DrawNewButton.Text       = 'Draw New';
            app.SS_DrawNewButton.FontColor  = [0, 0, 0];
            
        end

        % Button pushed function: SS_PushButton
        function SS_PushButtonPushed(app, event)
            
            % Write current roi mask & centroid to ybranch structure
            disp(['[' datestr(now) ']: ROI ' num2str(app.fov_rois_counter) ' marked complete, pushing sub-selected data for segmentation...']);
            
            % vertices & crop
            vx          = round(app.fov_rois(app.fov_rois_counter).Draw.Position(:, 1)); 
            vy          = round(app.fov_rois(app.fov_rois_counter).Draw.Position(:, 2)); 
            cropx       = min(vx):max(vx);
            cropy       = min(vy):max(vy);
            
            % mask in original fov and cropped (mask, projection, data);
            % note: remember that y is 1st dim in matrix notation, x is 2nd dim
            mask        = poly2mask(vx, vy, size(app.curr_proj, 1), size(app.curr_proj, 2));
            maskcrop    = mask(cropy, cropx);
            maskproj    = single(single(app.curr_proj(cropy, cropx)) .* maskcrop);
            maskdata    = uint16(reshape((reshape(single(app.img(cropy, cropx, :)), [size(maskcrop, 1) * size(maskcrop, 2), size(app.img, 3)]) .* reshape(maskcrop, [size(maskcrop, 1) * size(maskcrop, 2), 1])), [size(maskcrop, 1), size(maskcrop, 2), size(app.img, 3)]));
            
            % sink output data to ybranch structure
            app.ybranch.rois(app.fov_rois_counter).fullfov.vertices_x   = vx;
            app.ybranch.rois(app.fov_rois_counter).fullfov.vertices_y   = vy;
            app.ybranch.rois(app.fov_rois_counter).fullfov.mask_nopad   = mask;
            app.ybranch.rois(app.fov_rois_counter).cropped.fullbranch.mask_nopad   = maskcrop;
            app.ybranch.rois(app.fov_rois_counter).cropped.fullbranch.mask_proj_nopad  = maskproj;
            app.ybranch.rois(app.fov_rois_counter).cropped.fullbranch.mask_data_nopad  = maskdata;
            
            % mark complete
            app.new_draw_resolved = 1;
            
            % For polygons drawn on fullbranch, start at 1 or increment depending on already drawn; show remaining draws for current mask on button
            if ~isfield(app.ybranch.rois(app.fov_rois_counter).cropped, 'components')
                
                app.ybranch.rois(app.fov_rois_counter).cropped.components.drawn = 0;
                app.ybranch.rois(app.fov_rois_counter).segmented.status = 0;
                app.ybranch_mask_counter = 1;
                app.Thresh_DrawNewButton.Text = (['Draw New (' num2str(3 - app.ybranch.rois(app.fov_rois_counter).cropped.components.drawn) ')']);
                app.Thresh_DrawNewButton.FontColor  = [0, 0, 0];
                app.BranchSelectDropDown.Items = {'Select Branch'};
                app.BranchSelectDropDown.Value = 'Select Branch';
                app.Thresh_EditButton.Text = 'Edit';
                app.Thresh_EditButton.FontColor = [0, 0, 0];
                if isfield(app.Handles, 'centroid_draw')
                    if isvalid(app.Handles.centroid_draw)
                        app.Handles = rmfield(app.Handles, 'centroid_draw');
                    end
                end
                
                % reset some threshold/segment options
                app.SplineSmoothSpinner.Value = 0.2;
                SplineSmoothSpinnerValueChanged(app);
                app.ROIWeightSpinner.Value = 10;
                ROIWeightSpinnerValueChanged(app);
                app.ROITypeSelectDropDown.Value = 'contour';
                ROITypeSelectDropDownValueChanged(app);
                app.ManualCentroidCheckBox.Value = 0;
                ManualCentroidCheckBoxValueChanged(app);
                app.Thresh_SetThresholdSlider.Enable = 1;
                app.SplineSmoothSpinner.Enable = 1;
                app.ROIWeightSpinner.Enable = 1;
                app.ROITypeSelectDropDown.Enable = 1;
                app.ManualCentroidCheckBox.Enable = 1;
                
                % clear axes
                cla(app.YBranchCropped_Plot);
                cla(app.YBranchSegment_Plot);
                cla(app.TC_Plot_Proximal);
                cla(app.TC_Plot_Distal1);
                cla(app.TC_Plot_Distal2);
                
            else
                
                % throw warning for masks already present, plot the ones that are there
                mcinput = questdlg({'Warning!'; 'Oops! It looks like you''ve already PUSHED this ROI before...'; 'Do you want to REDRAW branches or just REVIEW?'; 'Beware, redrawing will delete old drawings for this Y-branch!'}, 'Redraw or Review?', 'Redraw', 'Review', 'Cancel', 'empty');
                if strcmp(mcinput, 'Redraw') % clear out the old ROIs and redraw
                    
                    disp(['[' datestr(now) ']: Clearing previously drawn branches for ROI ' num2str(app.fov_rois_counter) '...']); 
                    app.ybranch.rois(app.fov_rois_counter).cropped = rmfield(app.ybranch.rois(app.fov_rois_counter).cropped, 'components');
                    app.ybranch.rois(app.fov_rois_counter).cropped.components.drawn = 0;
                    app.ybranch.rois(app.fov_rois_counter).segmented.status = 0;
                    app.ybranch_mask_counter = 1;
                    app.Thresh_DrawNewButton.Text = (['Draw New (' num2str(4 - app.ybranch_mask_counter) ')']);
                    app.Thresh_DrawNewButton.FontColor  = [0, 0, 0];
                    app.BranchSelectDropDown.Items = {'Select Branch'};
                    app.BranchSelectDropDown.Value = 'Select Branch';
                    app.Thresh_EditButton.Text = 'Edit';
                    app.Thresh_EditButton.FontColor = [0, 0, 0];
                    if isfield(app.Handles, 'centroid_draw')
                        if isvalid(app.Handles.centroid_draw)
                            app.Handles = rmfield(app.Handles, 'centroid_draw');
                        end
                    end
                    
                    % reset some threshold/segment options
                    app.SplineSmoothSpinner.Value = 0.2;
                    SplineSmoothSpinnerValueChanged(app);
                    app.ROIWeightSpinner.Value = 10;
                    ROIWeightSpinnerValueChanged(app);
                    app.ROITypeSelectDropDown.Value = 'contour';
                    ROITypeSelectDropDownValueChanged(app);
                    app.ManualCentroidCheckBox.Value = 0;
                    ManualCentroidCheckBoxValueChanged(app);
                    app.Thresh_SetThresholdSlider.Enable = 1;
                    app.SplineSmoothSpinner.Enable = 1;
                    app.ROIWeightSpinner.Enable = 1;
                    app.ROITypeSelectDropDown.Enable = 1;
                    app.ManualCentroidCheckBox.Enable = 1;
                        
                    % clear axes
                    cla(app.YBranchCropped_Plot);
                    cla(app.YBranchSegment_Plot);
                    cla(app.TC_Plot_Proximal);
                    cla(app.TC_Plot_Distal1);
                    cla(app.TC_Plot_Distal2);
                    
                elseif strcmp(mcinput, 'Review') % just plot the old masks and timecourses
                    
                    % check current ROI counter; fix at 3 if already have the correct number
                    if app.ybranch.rois(app.fov_rois_counter).segmented.status == 1
                        
                        app.ybranch_mask_counter = 3;
                        app.Thresh_DrawNewButton.Text = ('Drawing Done');
                        app.Thresh_DrawNewButton.FontColor = [.6, .6, .6];
                        app.Thresh_EditButton.Text = ('Edits Locked');
                        app.Thresh_EditButton.FontColor = [.6, .6, .6];
                        app.Thresh_SetThresholdSlider.Enable = 0;
                        app.SplineSmoothSpinner.Enable = 0;
                        app.ROIWeightSpinner.Enable = 0;
                        app.ROITypeSelectDropDown.Enable = 0;
                        app.ManualCentroidCheckBox.Enable = 0;
                        
                    else
                        
                        msgbox({'Yikes!'; 'It looks like you started working on this Y-branch but didn''t quite finish it...'; 'It''s suggested that you delete or redraw this one, otherwise it will stay incomplete!'}, 'Warning', 'warn');
                        app.ybranch_mask_counter = app.ybranch.rois(app.fov_rois_counter).cropped.components.drawn + 1;
                        app.Thresh_DrawNewButton.Text = (['Draw New (' num2str(4 - app.ybranch_mask_counter) ')']);
                        app.Thresh_DrawNewButton.FontColor  = [0, 0, 0];
                        app.Thresh_EditButton.Text = 'Edit';
                        app.Thresh_EditButton.FontColor = [0, 0, 0];
                        app.Thresh_SetThresholdSlider.Enable = 1;
                        app.SplineSmoothSpinner.Enable = 1;
                        app.ROIWeightSpinner.Enable = 1;
                        app.ROITypeSelectDropDown.Enable = 1;
                        app.ManualCentroidCheckBox.Enable = 1;
                        
                    end
                    
                    % reinstate some threshold/segment options from ybranch structure
                    app.SplineSmoothSpinner.Value = app.ybranch.rois(app.fov_rois_counter).segmented.spline_smooth;
                    SplineSmoothSpinnerValueChanged(app);
                    app.SplineSmoothSpinner.Enable = 0;
                    app.ROIWeightSpinner.Value = app.ybranch.rois(app.fov_rois_counter).segmented.roi_weight;
                    ROIWeightSpinnerValueChanged(app);
                    app.ROIWeightSpinner.Enable = 0;
                    app.ROITypeSelectDropDown.Value = app.ybranch.rois(app.fov_rois_counter).segmented.plot_roi_type;
                    ROITypeSelectDropDownValueChanged(app);
                    app.ROITypeSelectDropDown.Enable = 0;
                    if strcmp(app.ybranch.rois(app.fov_rois_counter).segmented.centroid_method, 'manual')
                        app.ManualCentroidCheckBox.Value = 1;
                        app.Handles.centroid_coordinate = app.ybranch.rois(app.fov_rois_counter).segmented.centroid;
                    else
                        app.ManualCentroidCheckBox.Value = 0;
                        if isfield(app.Handles, 'centroid_draw')
                            app.Handles = rmfield(app.Handles, 'centroid_draw');
                        end
                    end
                    ManualCentroidCheckBoxValueChanged(app);
                    app.ManualCentroidCheckBox.Enable = 0;
                    
                    % clear axes
                    cla(app.YBranchCropped_Plot);
                    cla(app.YBranchSegment_Plot);
                    cla(app.TC_Plot_Proximal);
                    cla(app.TC_Plot_Distal1);
                    cla(app.TC_Plot_Distal2);
                   
                else
                    
                    disp(['[' datestr(now) ']: Push canceled...']); 
                    
                end
                
            end
            
            % plot completed ybranch to app.YBranchPlots
            plot_ybranch_crop(app);
            

        end

        % Value changed function: Thresh_SetThresholdSlider
        function Thresh_SetThresholdSliderValueChanged(app, event)
            
            % read slider properties
            value   = app.Thresh_SetThresholdSlider.Value;
            limits  = app.Thresh_SetThresholdSlider.Limits;
            
            % sync slider value to spinner
            app.Thresh_SetThresholdSpinner.Value = value/limits(2);
            
            % plot completed ybranch to app.YBranchPlots
            if app.Thresh_SetThresholdSlider.Enable
                if ~isempty(app.ybranch) % check to see if we have anything to plot
                    bname = app.BranchSelectDropDown.Value;
                    eval(['app.ybranch.rois(app.fov_rois_counter).cropped.components.' bname '.thresh = value / limits(2);']);
                    plot_ybranch_crop(app);
                end
            end
            
        end

        % Button pushed function: Thresh_DrawNewButton
        function Thresh_DrawNewButtonPushed(app, event)
            
            if ~strcmp(app.Thresh_DrawNewButton.Text, 'Drawing Done')
                
                % command line feedback
                disp(['[' datestr(now) ']: Drawing new sub-branch mask for current Y-branch...']);
                
                bpush = 1;
                while bpush == 1
                    
                    % if 3 branch masks already drawn, spitback error; otherwise, continue to drawing
                    if app.ybranch.rois(app.fov_rois_counter).cropped.components.drawn >= 3
                        
                        msgbox({'Error!'; 'Err... Something is wrong!'; ['We see ' num2str(app.ybranch.rois(app.fov_rois_counter).cropped.components.drawn) ' branch masks already drawn here!']; 'Try something else (e.g., re-PUSH the full branch mask) and come back...'}, 'Error', 'error');
                        
                    else
                        
                        % change button appearance to reinforce drawing is active
                        app.Thresh_DrawNewButton.Text       = ['Drawing ' num2str(app.ybranch_mask_counter)];
                        app.Thresh_DrawNewButton.FontColor  = [.85, 0, 0];
                        
                        % unselect other Y-branch drawings
                        app.BranchSelectDropDown.Value = 'Select Branch';
                        BranchSelectDropDownValueChanged(app);
                        
                        % draw branch point mask on Y-branch
                        bname = (['branch' num2str(app.ybranch_mask_counter)]);
                        branch_roi_opts = [];
                        branch_roi_opts.Label         = bname;
                        branch_roi_opts.LineWidth     = 1.5; 
                        branch_roi_opts.Selected      = 1;                  % automatically select the current drawing
                        branch_roi_opts.SelectedColor = [1, 1, 0];         % selection color is orange
                        branch_roi_opts.Color         = [0, .5, 1];         % default color is blue
                        branch_roi_opts.InteractionsAllowed   = 'none';     % 'none' will lock ROI to edits
                        branch_roi_opts.FaceSelectable        = 1;
                        mhandle = drawpolygon(app.YBranchCropped_Plot, branch_roi_opts);   % mask polygon object handle
                        
                        % generate binary mask
                        mask = poly2mask(mhandle.Position(:, 1), mhandle.Position(:, 2), size(app.ybranch.rois(app.fov_rois_counter).cropped.fullbranch.mask, 1), size(app.ybranch.rois(app.fov_rois_counter).cropped.fullbranch.mask, 2));
                        
                        % sync out the branch mask
                        eval(['app.ybranch.rois(app.fov_rois_counter).cropped.components.' bname '.mask = mask;']);
                        eval(['app.ybranch.rois(app.fov_rois_counter).cropped.components.' bname '.thresh = 1;']);
                        eval(['app.ybranch.rois(app.fov_rois_counter).cropped.components.' bname '.mask_handle = mhandle;']);
                                            
                        % Add newly drawn branch ROI to dropdown & select
                        app.BranchSelectDropDown.Items = [app.BranchSelectDropDown.Items, bname];
                        app.BranchSelectDropDown.Value = bname;
                        BranchSelectDropDownValueChanged(app);
                        
                        % call the thresholder to overlay the branch mask within the drawn space
                        app.Thresh_SetThresholdSlider.Value = 1;
                        Thresh_SetThresholdSliderValueChanged(app);
                                            
                        % increment the counter; fix at 3 if already have the correct number
                        if app.ybranch_mask_counter < 3
                            app.ybranch_mask_counter = app.ybranch_mask_counter + 1;
                            app.ybranch.rois(app.fov_rois_counter).cropped.components.drawn = app.ybranch.rois(app.fov_rois_counter).cropped.components.drawn + 1;
                            app.Thresh_DrawNewButton.Text = (['Draw New (' num2str(4 - app.ybranch_mask_counter) ')']);
                            app.Thresh_DrawNewButton.FontColor  = [0, 0, 0];
                        elseif app.ybranch_mask_counter == 3
                            app.ybranch_mask_counter = app.ybranch_mask_counter;
                            app.ybranch.rois(app.fov_rois_counter).cropped.components.drawn = 3;
                            app.Thresh_DrawNewButton.Text = ('Drawing Done');
                            app.Thresh_DrawNewButton.FontColor = [.6, .6, .6];
                        else
                            app.Thresh_DrawNewButton.Text = ('Drawing Done');
                            app.Thresh_DrawNewButton.FontColor = [.6, .6, .6];
                        end
                        
                    end
                    
                    % end while condition
                    bpush = 0;
                    
                end
                
            end
        end

        % Button pushed function: Thresh_SegmentButton
        function Thresh_SegmentButtonPushed(app, event)
            
            % % grab spline smoothness from spinner - default is 0.2
            SplineSmoothSpinnerValueChanged(app);
            
            % grab roi weight from spinnner - default is 10
            ROIWeightSpinnerValueChanged(app);
            
            % set roi type - 'contour' is default
            ROITypeSelectDropDownValueChanged(app);
            
            % auto vs. manual centroid check box
            if isfield(app.Handles, 'centroid_draw')
                if isvalid(app.Handles.centroid_draw)
                    app.Handles.centroid_coordinate = app.Handles.centroid_draw.Center;
                end
            end
            ManualCentroidCheckBoxValueChanged(app);
            
            % plot segmented branches with splines
            plot_ybranch_segment(app);
            
            % plot timecourses for segmented branches
            plot_ybranch_timecourses(app);

        end

        % Value changed function: ROISelectDropDown
        function ROISelectDropDownValueChanged(app, event)
            
            % get value for current selection
            value = app.ROISelectDropDown.Value;
            
            % highlight selected ROI mask on input image
            if ~strcmp(value, 'Select')
                vv = str2num(value);
                disp(['[' datestr(now) ']: ROI ' num2str(vv) ' selected...']);
                for ii = 1:size(app.fov_rois, 2)
                    if ii == vv
                        app.fov_rois_counter = ii;
                        app.fov_rois(ii).Draw.Selected = 1;
                        app.fov_rois(ii).Draw.Visible = 'on';
                        app.fov_rois(ii).Draw.InteractionsAllowed = 'all';
                    else
                        app.fov_rois(ii).Draw.Selected = 0;
                        if strcmp(app.DrawnROIsSwitch.Value, 'Hide')
                            app.fov_rois(ii).Draw.Visible = 'off';
                        end
                        app.fov_rois(ii).Draw.InteractionsAllowed = 'none';
                    end
                end
            else
                for ii = 1:size(app.fov_rois, 2)
                    app.fov_rois(ii).Draw.Selected = 0;
                    if strcmp(app.DrawnROIsSwitch.Value, 'Hide')
                        app.fov_rois(ii).Draw.Visible = 'off';
                    end
                    app.fov_rois(ii).Draw.InteractionsAllowed = 'none';
                end
            end
            
        end

        % Button pushed function: SS_DeleteButton
        function SS_DeleteButtonPushed(app, event)
            
            mcinput = questdlg({['Are you ABSOLUTELY SURE you want to delete ROI #' app.ROISelectDropDown.Value '?']});
            if strcmp(mcinput, 'Yes') % clear out the old ROIs and redraw
                
                % command line feedback
                disp(['[' datestr(now) ']: Deleting ROI ' app.ROISelectDropDown.Value ' and re-distributing labels...']);
                
                % re-show all ROIs
                app.DrawnROIsSwitch.Value = 'Show';
                DrawnROIsSwitchValueChanged(app);
                
                % delete current selection
                if ~strcmp(app.ROISelectDropDown.Value, 'Select')
                    
                    app.fov_rois(app.fov_rois_counter).Draw.Visible = 'off';
                    app.ROISelectDropDown.Items = app.ROISelectDropDown.Items(1:end-1);
                    
                    % increment remaining rois down in number
                    ii = app.fov_rois_counter;
                    while ii < size(app.ybranch.rois, 2)
                        
                        % shift data down in ybranch and fov_rois
                        app.ybranch.rois(ii) = app.ybranch.rois(ii + 1);
                        app.fov_rois(ii) = app.fov_rois(ii + 1);
                        
                        % relabel ROIs that were incremented down
                        app.fov_rois(ii).Draw.Label = num2str(ii);
                        
                        % increment counter
                        ii = ii + 1;
                        
                    end
                    
                    % empty out final structure entry, which has now been shifted down a slot
                    if app.new_draw_resolved == 1
                        app.ybranch.rois(size(app.ybranch.rois, 2)) = [];
                    end
                    app.fov_rois(size(app.fov_rois, 2)) = [];
                    
                    % re-select ROI from dropdown menu
                    ROISelectDropDownValueChanged(app);
                    
                    % reset new draw resolver
                    app.new_draw_resolved = 1;
                   
                else 
                    
                    msgbox({'Oops!'; 'Looks like you haven''t selected a mask to delete from the dropdown...'}, 'Warning', 'warn');
                    
                end
                
            else
                
                % delete canceled
                
            end
            
        end

        % Value changed function: DrawnROIsSwitch
        function DrawnROIsSwitchValueChanged(app, event)
            
            value = app.DrawnROIsSwitch.Value;
            
            if strcmp(value, 'Hide')
                
                % command line feedback
                disp(['[' datestr(now) ']: Hiding unselected ROIs...']);
                
                % hide ROIs except selection (according to app.fov_rois_counter
                for ii = [1:app.fov_rois_counter-1, app.fov_rois_counter+1:size(app.fov_rois, 2)]
                    app.fov_rois(ii).Draw.Visible = 'off';
                end
                
            elseif strcmp(value, 'Show')
                
                % command line feedback
                disp(['[' datestr(now) ']: Showing unselected ROIs...']);
                
                % hide ROIs except selection (according to app.fov_rois_counter
                for ii = [1:app.fov_rois_counter-1, app.fov_rois_counter+1:size(app.fov_rois, 2)]
                    app.fov_rois(ii).Draw.Visible = 'on';
                end
                
            end

        end

        % Button pushed function: TC_ExitButton
        function TC_ExitButtonPushed(app, event)
            
            mcinput = questdlg({'Warning!'; 'GUI will shut down and unsaved progress on ROIs will be lost!'; 'Do you want to proceed?'});
            if strcmp(mcinput, 'Yes')
                
                % cancel reload if anything but "Yes"
                disp(['[' datestr(now) ']: GUI shutting down...']);
                close(app.UIFigure);
                
            end
            
        end

        % Button pushed function: TC_RefreshButton
        function TC_RefreshButtonPushed(app, event)
            
            mcinput = questdlg({'Warning!'; 'All figures will be cleared and unsaved progress on ROIs will be lost!'; 'Do you want to proceed?'});
            if strcmp(mcinput, 'Yes')
                
                % cancel reload if anything but "Yes"
                disp(['[' datestr(now) ']: Refreshing GUI...']);
                
                % clear out current file & data structures
                app.current_file = [];
                app.out_dir = [];
                app.fov_rois = [];
                app.ybranch = [];
                
                % flush components & restore defaults
                app.fov_rois_counter = 0;
                cla(app.InputImage);
                cla(app.YBranchCropped_Plot);
                cla(app.YBranchSegment_Plot);
                cla(app.TC_Plot_Proximal);
                cla(app.TC_Plot_Distal1);
                cla(app.TC_Plot_Distal2);
                app.FrameSelect_Slider.Value = 1;
                FrameSelect_SliderValueChanged(app);
                app.FrameSelect_Spinner.Value = 1;
                FrameSelect_SpinnerValueChanged(app);
                app.Proj_NoneButton.Value = 1;
                Proj_ButtonGroupSelectionChanged(app);
                app.Proj_WindowSizeEditField.Value = 100;
                Proj_WindowSizeEditFieldValueChanged(app);
                app.ROISelectDropDown.Items = {'Select'};
                app.ROISelectDropDown.Value = 'Select';
                ROISelectDropDownValueChanged(app);
                app.BranchSelectDropDown.Items = {'Select Branch'};
                app.BranchSelectDropDown.Value = 'Select Branch';
                BranchSelectDropDownValueChanged(app);
                app.Thresh_SetThresholdSlider.Value = 1;
                Thresh_SetThresholdSliderValueChanged(app);
                app.Thresh_EditButton.Text = 'Edit';
                app.Thresh_EditButton.FontColor = [0, 0, 0];
                app.SS_DrawNewButton.Text       = 'Draw New';
                app.SS_DrawNewButton.FontColor  = [0, 0, 0];
                app.Thresh_SetThresholdSlider.Enable = 1;
                
            end
            
        end

        % Value changed function: BranchSelectDropDown
        function BranchSelectDropDownValueChanged(app, event)
            
            % get value for current selection
            value = app.BranchSelectDropDown.Value;
            
            % reset threshold slider
            app.Thresh_SetThresholdSlider.Value = 1;
            
            % highlight selected ROI mask on input image
            if ~strcmp(value, 'Select Branch')
                vv = str2num(value(end));
                disp(['[' datestr(now) ']: Branch ' num2str(vv) ' selected...']);
                for ii = 1:app.ybranch.rois(app.fov_rois_counter).cropped.components.drawn
                    bname = (['branch' num2str(ii)]);
                    if isvalid(eval(['app.ybranch.rois(app.fov_rois_counter).cropped.components.' bname '.mask_handle']))
                        if ii == vv
                             eval(['app.ybranch.rois(app.fov_rois_counter).cropped.components.' bname '.mask_handle.Selected = 1;']);
                             eval(['app.ybranch.rois(app.fov_rois_counter).cropped.components.' bname '.mask_handle.InteractionsAllowed = ''none'';']);
                        else
                             eval(['app.ybranch.rois(app.fov_rois_counter).cropped.components.' bname '.mask_handle.Selected = 0;']);
                             eval(['app.ybranch.rois(app.fov_rois_counter).cropped.components.' bname '.mask_handle.InteractionsAllowed = ''none'';']);
                        end
                    end
                end
            else
                if ~isempty(app.ybranch)
                    if app.fov_rois_counter > 0 
                        if isfield(app.ybranch.rois(app.fov_rois_counter), 'cropped')
                            if isfield(app.ybranch.rois(app.fov_rois_counter).cropped, 'components')
                                for ii = 1:app.ybranch.rois(app.fov_rois_counter).cropped.components.drawn
                                    bname = (['branch' num2str(ii)]);
                                    if isvalid(eval(['app.ybranch.rois(app.fov_rois_counter).cropped.components.' bname '.mask_handle']))
                                        eval(['app.ybranch.rois(app.fov_rois_counter).cropped.components.' bname '.mask_handle.Selected = 0;']);
                                        eval(['app.ybranch.rois(app.fov_rois_counter).cropped.components.' bname '.mask_handle.InteractionsAllowed = ''none'';']);
                                    end
                                end
                            end
                        end
                    end
                end
            end
            
        end

        % Button pushed function: Thresh_EditButton
        function Thresh_EditButtonPushed(app, event)
            
            % get value for current dropdown selection
            value = app.BranchSelectDropDown.Value;
            
            if ~strcmp(value, 'Select Branch')
                
                % if editing, make branch editable; if done editing, replot thresholded mask and lock branch drawing
                if strcmp(app.Thresh_EditButton.Text, 'Edit')
                    
                    % change button labeling to indicate branch is being edited
                    app.Thresh_EditButton.Text = 'Done Editing';
                    app.Thresh_EditButton.FontColor = [.85, 0, 0];
                    
                    % highlight selected ROI mask on input image
                    if ~strcmp(value, 'Select Branch')
                        vv = str2num(value(end));
                        disp(['[' datestr(now) ']: Branch ' num2str(vv) ' selected...']);
                        for ii = 1:app.ybranch.rois(app.fov_rois_counter).cropped.components.drawn
                            bname = (['branch' num2str(ii)]);
                            if ii == vv
                                 eval(['app.ybranch.rois(app.fov_rois_counter).cropped.components.' bname '.mask_handle.Selected = 1;'])
                                 eval(['app.ybranch.rois(app.fov_rois_counter).cropped.components.' bname '.mask_handle.InteractionsAllowed = ''all'';']);
                            else
                                 eval(['app.ybranch.rois(app.fov_rois_counter).cropped.components.' bname '.mask_handle.Selected = 0;']);
                                 eval(['app.ybranch.rois(app.fov_rois_counter).cropped.components.' bname '.mask_handle.InteractionsAllowed = ''none'';']);
                            end
                        end
                    else
                        for ii = 1:app.ybranch.rois(app.fov_rois_counter).cropped.components.drawn
                            bname = (['branch' num2str(ii)]);
                            eval(['app.ybranch.rois(app.fov_rois_counter).cropped.components.' bname '.mask_handle.Selected = 0;']);
                            eval(['app.ybranch.rois(app.fov_rois_counter).cropped.components.' bname '.mask_handle.InteractionsAllowed = ''none'';']);
                        end
                    end
                    
                elseif strcmp(app.Thresh_EditButton.Text, 'Done Editing')
                    
                    % change button labeling to indicate branch is done being edited
                    app.Thresh_EditButton.Text = 'Edit';
                    app.Thresh_EditButton.FontColor = [0, 0, 0];
                    
                    % make branches non-interactable again
                    if ~strcmp(value, 'Select Branch')
                        vv = str2num(value(end));
                        disp(['[' datestr(now) ']: Branch ' num2str(vv) ' selected...']);
                        for ii = 1:app.ybranch.rois(app.fov_rois_counter).cropped.components.drawn
                            bname = (['branch' num2str(ii)]);
                            if ii == vv
                                eval(['app.ybranch.rois(app.fov_rois_counter).cropped.components.' bname '.mask_handle.Selected = 1;']);
                                eval(['app.ybranch.rois(app.fov_rois_counter).cropped.components.' bname '.mask_handle.InteractionsAllowed = ''none'';']);
                                
                                % update mask components for edited mask, sync out to ybranch structure
                                mhandle = eval(['app.ybranch.rois(app.fov_rois_counter).cropped.components.' bname '.mask_handle;']);
                                mask = poly2mask(mhandle.Position(:, 1), mhandle.Position(:, 2), size(app.ybranch.rois(app.fov_rois_counter).cropped.fullbranch.mask, 1), size(app.ybranch.rois(app.fov_rois_counter).cropped.fullbranch.mask, 2));
                                eval(['app.ybranch.rois(app.fov_rois_counter).cropped.components.' bname '.mask = mask;']);
                                eval(['app.ybranch.rois(app.fov_rois_counter).cropped.components.' bname '.thresh = 1;']);
                                eval(['app.ybranch.rois(app.fov_rois_counter).cropped.components.' bname '.mask_handle = mhandle;']);
                                 
                            else
                                eval(['app.ybranch.rois(app.fov_rois_counter).cropped.components.' bname '.mask_handle.Selected = 0;']);
                                eval(['app.ybranch.rois(app.fov_rois_counter).cropped.components.' bname '.mask_handle.InteractionsAllowed = ''none'';']);
                            end
                        end
                    else
                        for ii = 1:app.ybranch.rois(app.fov_rois_counter).cropped.components.drawn
                            bname = (['branch' num2str(ii)]);
                            eval(['app.ybranch.rois(app.fov_rois_counter).cropped.components.' bname '.mask_handle.Selected = 0;']);
                            eval(['app.ybranch.rois(app.fov_rois_counter).cropped.components.' bname '.mask_handle.InteractionsAllowed = ''none'';']);
                        end
                    end
                    
                    % update thresholded mask overlay
                    Thresh_SetThresholdSliderValueChanged(app);
                    
                end
                
            else
                
                % Warn user that they need to select a mask to edit from the drop down
                msgbox({'Oops!'; 'You haven''t selected a mask to edit from the dropdown yet...'}, 'Warning', 'warn');
                
            end
            
        end

        % Value changed function: SplineSmoothSpinner
        function SplineSmoothSpinnerValueChanged(app, event)
            
            value = app.SplineSmoothSpinner.Value;
            
            % plot completed ybranch to app.YBranchPlots
            if ~isempty(app.ybranch) % check to see if we have anything to plot
                eval(['app.ybranch.rois(app.fov_rois_counter).segmented.spline_smooth = value;']);
            end
            
        end

        % Value changed function: ROIWeightSpinner
        function ROIWeightSpinnerValueChanged(app, event)
            
            value = app.ROIWeightSpinner.Value;
            
            % plot completed ybranch to app.YBranchPlots
            if ~isempty(app.ybranch) % check to see if we have anything to plot
                eval(['app.ybranch.rois(app.fov_rois_counter).segmented.roi_weight = value;']);
            end
            
        end

        % Value changed function: ROITypeSelectDropDown
        function ROITypeSelectDropDownValueChanged(app, event)
            
            value = app.ROITypeSelectDropDown.Value;
            
            % plot completed ybranch to app.YBranchPlots
            if ~isempty(app.ybranch) % check to see if we have anything to plot
                if strcmp(value, 'contour')
                    eval(['app.ybranch.rois(app.fov_rois_counter).segmented.plot_roi_type = ''contour'';']);
                elseif strcmp(value, 'rectangle')
                    eval(['app.ybranch.rois(app.fov_rois_counter).segmented.plot_roi_type = ''rectangle'';']);
                elseif strcmp(value, 'spline')
                    eval(['app.ybranch.rois(app.fov_rois_counter).segmented.plot_roi_type = ''spline'';']);
                end
            end
            
        end

        % Value changed function: ManualCentroidCheckBox
        function ManualCentroidCheckBoxValueChanged(app, event)
            
            value = app.ManualCentroidCheckBox.Value;
            
            if ~isempty(app.ybranch) % check to see if we have anything to plot
                if value == 1
                    eval(['app.ybranch.rois(app.fov_rois_counter).segmented.centroid_method = ''manual'';']);
                else
                    eval(['app.ybranch.rois(app.fov_rois_counter).segmented.centroid_method = ''auto'';']);
                end
            end
            
        end

        % Button pushed function: TC_SyncROIsButton
        function TC_SyncROIsButtonPushed(app, event)
            
            % mark segmentation complete
            app.ybranch.rois(app.fov_rois_counter).segmented.status == 1;
            
            % lock threshold/segmentation options
            app.ybranch_mask_counter = 3;
            app.Thresh_DrawNewButton.Text = ('Drawing Done');
            app.Thresh_DrawNewButton.FontColor = [.6, .6, .6];
            app.Thresh_EditButton.Text = ('Edits Locked');
            app.Thresh_EditButton.FontColor = [.6, .6, .6];
            app.Thresh_SetThresholdSlider.Enable = 0;
            app.SplineSmoothSpinner.Enable = 0;
            app.ROIWeightSpinner.Enable = 0;
            app.ROITypeSelectDropDown.Enable = 0;
            app.ManualCentroidCheckBox.Enable = 0;
            
            % check for selected output dir and prompt if missing
            if isempty(app.out_dir)
                TC_SaveAsButtonPushed(app);
            end
            if ~exist(app.out_dir, 'dir')
                TC_SaveAsButtonPushed(app);
            end
            
            % save out current ybranch structure & give it a descriptive name
            cd(app.out_dir);
            ybranch_data = app.ybranch.rois(app.fov_rois_counter);
            out_file = uiputfile([app.out_dir '/*'], 'Save Y-Branch ROI Data', ['roi' sprintf('%02.f', app.fov_rois_counter) '_ybranch_segmented_data.mat']);
            disp(['[' datestr(now) ']: Saving out current ROI (' app.out_dir '/' out_file ')...']);
            save([app.out_dir '/' out_file], 'ybranch_data', '-mat');
            
        end

        % Button pushed function: TC_SaveAsButton
        function TC_SaveAsButtonPushed(app, event)
            
            % dialog box to select output destination
            select_path = uigetdir(['Select parent folder for ''ybranch''']);
            
            % make directory
            if ~exist(app.out_dir, 'dir')
                app.out_dir = [select_path '/ybranch'];
                mkdir(app.out_dir);
            else
                % warn and ask to use same folder or use unique
                mcinput = questdlg({'Warning!'; 'The destination you selected already contains a folder named ''ybranch''...'; 'Do you want to proceed with writing outputs to this folder, or use a unique name (''ybranch_YYMMDDHHMMSS'')?'}, '', 'Proceed', 'Use Unique', 'Cancel', '');
                if strcmp(mcinput, 'Proceed')
                    app.out_dir = [select_path '/ybranch'];
                elseif strcmp(mcinput, 'Use Unique')
                    app.out_dir = [select_path '/ybranch_' datestr(now, 'yyyymmddHHMMSS')];
                    mkdir(app.out_dir);
                end
            end
            
        end

        % Value changed function: Thresh_SetThresholdSpinner
        function Thresh_SetThresholdSpinnerValueChanged(app, event)
            
            % sync slider value to spinner
            value = app.Thresh_SetThresholdSpinner.Value;
            
            % read slider properties
            app.Thresh_SetThresholdSlider.Value = value * app.Thresh_SetThresholdSlider.Limits(2);
            
            % plot completed ybranch to app.YBranchPlots
            if app.Thresh_SetThresholdSlider.Enable
                if ~isempty(app.ybranch) % check to see if we have anything to plot
                    bname = app.BranchSelectDropDown.Value;
                    eval(['app.ybranch.rois(app.fov_rois_counter).cropped.components.' bname '.thresh = value;']);
                    plot_ybranch_crop(app);
                end
            end
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {886, 886};
                app.GridLayout.ColumnWidth = {'1x'};
                app.YBranchPlots.Layout.Row = 2;
                app.YBranchPlots.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {605, '1x'};
                app.YBranchPlots.Layout.Row = 1;
                app.YBranchPlots.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.Text = 'Open';
            app.OpenNewMenu = uimenu(app.OpenMenu);
            app.OpenNewMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenNewMenuSelected, true);
            app.OpenNewMenu.Text = 'Open New';
            app.ReloadCurrentMenu = uimenu(app.OpenMenu);
            app.ReloadCurrentMenu.MenuSelectedFcn = createCallbackFcn(app, @ReloadCurrentMenuSelected, true);
            app.ReloadCurrentMenu.Text = 'Reload Current';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.MenuSelectedFcn = createCallbackFcn(app, @HelpMenuSelected, true);
            app.HelpMenu.Text = 'Help';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.StackIn = uipanel(app.GridLayout);
            app.InputImage = uiaxes(app.StackIn);
            title(app.InputImage, 'Input Image Stack')
            app.InputImage.XTick = [];
            app.InputImage.YTick = [];
            app.SS_label = uilabel(app.StackIn);
            app.SS_label.Text = {'Input Stack Y-Branch Selector'; '1) Load a two-photon image stack and control its appearance'; '2) Click "Draw New" to draw a polygonal ROI around a dendrite Y-branch'; '3) Click "Push" to send selected Y-branch to threshold/segmentation tabs'; '* "Edit" & "Delete" old ROIs as needed'};
            app.FrameSelectLabel = uilabel(app.StackIn);
            app.FrameSelectLabel.Text = 'Frame Select';
            app.FrameSelect_Slider = uislider(app.StackIn);
            app.FrameSelect_Slider.MajorTicks = [];
            app.FrameSelect_Slider.ValueChangedFcn = createCallbackFcn(app, @FrameSelect_SliderValueChanged, true);
            app.SS_DrawNewButton = uibutton(app.StackIn, 'push');
            app.SS_DrawNewButton.ButtonPushedFcn = createCallbackFcn(app, @SS_DrawNewButtonPushed, true);
            app.SS_DrawNewButton.Text = 'Draw New';
            app.Proj_ButtonGroup = uibuttongroup(app.StackIn);
            app.Proj_ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Proj_ButtonGroupSelectionChanged, true);
            app.Proj_ButtonGroup.Title = 'Projection Method (select window size)';
            app.Proj_NoneButton = uiradiobutton(app.Proj_ButtonGroup);
            app.Proj_NoneButton.Text = 'None';
            app.Proj_NoneButton.Value = true;
            app.Proj_MaxButton = uiradiobutton(app.Proj_ButtonGroup);
            app.Proj_MaxButton.Text = 'Maximum';
            app.Proj_VarButton = uiradiobutton(app.Proj_ButtonGroup);
            app.Proj_VarButton.Text = 'Variance';
            app.Proj_AvgButton = uiradiobutton(app.Proj_ButtonGroup);
            app.Proj_AvgButton.Text = 'Average';
            app.WindowSizeframesLabel = uilabel(app.StackIn);
            app.WindowSizeframesLabel.Text = {'Window Size'; '(# frames)'};
            app.Proj_WindowSizeEditField = uieditfield(app.StackIn, 'numeric');
            app.Proj_WindowSizeEditField.Limits = [1 Inf];
            app.Proj_WindowSizeEditField.RoundFractionalValues = 'on';
            app.Proj_WindowSizeEditField.ValueChangedFcn = createCallbackFcn(app, @Proj_WindowSizeEditFieldValueChanged, true);
            app.Proj_WindowSizeEditField.Value = 100;
            app.SS_PushButton = uibutton(app.StackIn, 'push');
            app.SS_PushButton.ButtonPushedFcn = createCallbackFcn(app, @SS_PushButtonPushed, true);
            app.SS_PushButton.Text = 'Push';
            app.SS_DeleteButton = uibutton(app.StackIn, 'push');
            app.SS_DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @SS_DeleteButtonPushed, true);
            app.SS_DeleteButton.Text = 'Delete';
            app.FrameSelect_Spinner = uispinner(app.StackIn);
            app.FrameSelect_Spinner.Limits = [1 Inf];
            app.FrameSelect_Spinner.RoundFractionalValues = 'on';
            app.FrameSelect_Spinner.ValueChangedFcn = createCallbackFcn(app, @FrameSelect_SpinnerValueChanged, true);
            app.FrameSelect_Spinner.Value = 1;
            app.MinEditFieldLabel = uilabel(app.StackIn);
            app.MinEditFieldLabel.Text = 'Min';
            app.ColormapMinEditField = uieditfield(app.StackIn, 'numeric');
            app.ColormapMinEditField.Limits = [0 Inf];
            app.ColormapMinEditField.ValueChangedFcn = createCallbackFcn(app, @ColormapMinEditFieldValueChanged, true);
            app.MaxEditFieldLabel = uilabel(app.StackIn);
            app.MaxEditFieldLabel.Text = 'Max';
            app.ColormapMaxEditField = uieditfield(app.StackIn, 'numeric');
            app.ColormapMaxEditField.Limits = [0 Inf];
            app.ColormapMaxEditField.ValueChangedFcn = createCallbackFcn(app, @ColormapMaxEditFieldValueChanged, true);
            app.ColormapMaxEditField.Value = 1000;
            app.ColormapEditLabel = uilabel(app.StackIn);
            app.ColormapEditLabel.Text = 'Colormap';
            app.DrawnROIsLabel = uilabel(app.StackIn);
            app.DrawnROIsLabel.Text = 'Drawn ROIs';
            app.DrawnROIsSwitch = uiswitch(app.StackIn, 'slider');
            app.DrawnROIsSwitch.Items = {'Hide', 'Show'};
            app.DrawnROIsSwitch.ValueChangedFcn = createCallbackFcn(app, @DrawnROIsSwitchValueChanged, true);
            app.DrawnROIsSwitch.Value = 'Show';
            app.ROISelectDropDownLabel = uilabel(app.StackIn);
            app.ROISelectDropDownLabel.Text = 'ROI Select';
            app.ROISelectDropDown = uidropdown(app.StackIn);
            app.ROISelectDropDown.Items = {'Select'};
            app.ROISelectDropDown.ValueChangedFcn = createCallbackFcn(app, @ROISelectDropDownValueChanged, true);
            app.ROISelectDropDown.Value = 'Select';
            app.YBranchPlots = uipanel(app.GridLayout);
            app.YBranchCropped_Plot = uiaxes(app.YBranchPlots);
            title(app.YBranchCropped_Plot, 'Y-Branch, Thresholded')
            app.YBranchCropped_Plot.XTick = [];
            app.YBranchCropped_Plot.YTick = [];
            app.YBranchSegment_Plot = uiaxes(app.YBranchPlots);
            title(app.YBranchSegment_Plot, 'Y-Branch, Segmented')
            app.YBranchSegment_Plot.XTick = [];
            app.YBranchSegment_Plot.YTick = [];
            app.TimecoursesPanel = uipanel(app.YBranchPlots);
            app.TimecoursesPanel.Title = 'Timecourses of Segmented Y-branch';
            app.TC_Plot_Proximal = uiaxes(app.TimecoursesPanel);
            ylabel(app.TC_Plot_Proximal, 'Raw F')
            app.TC_Plot_Proximal.XTick = [];
            app.TC_Plot_Proximal.YTick = [];
            app.TC_Plot_Proximal.YTickLabel = '';
            app.TC_Plot_Distal1 = uiaxes(app.TimecoursesPanel);
            ylabel(app.TC_Plot_Distal1, 'Raw F')
            app.TC_Plot_Distal1.XTick = [];
            app.TC_Plot_Distal1.YTick = [];
            app.TC_Plot_Distal1.YTickLabel = '';
            app.TC_Plot_Distal2 = uiaxes(app.TimecoursesPanel);
            ylabel(app.TC_Plot_Distal2, 'Raw F')
            app.TC_Plot_Distal2.XTick = [];
            app.TC_Plot_Distal2.YTick = [];
            app.TC_Plot_Distal2.YTickLabel = '';
            app.ThreshAndSeg_Label = uilabel(app.YBranchPlots);
            app.ThreshAndSeg_Label.Text = {'Y-Branch Threshold & Segment:'; '1) Click "Draw New" to draw polygonal ROIs around each Y-branch component (i.e., branch1, branch2, branch3). '; '2) Each Y-branch component can be thresholded (using the slider) and edited ("Edit") independently'; '3) Click "Segment" to generate spline fits, calcualte angles, and label the current Y-branch'; '4) Control segmentation (spline smoothing, ROI type & weight), drag/drop a "Manual Centroid", and update by re-clicking "Segment"'};
            app.SetThresholdSliderLabel = uilabel(app.YBranchPlots);
            app.SetThresholdSliderLabel.Text = 'Set Threshold';
            app.Thresh_SetThresholdSlider = uislider(app.YBranchPlots);
            app.Thresh_SetThresholdSlider.Limits = [0 1000];
            app.Thresh_SetThresholdSlider.MajorTicks = [];
            app.Thresh_SetThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @Thresh_SetThresholdSliderValueChanged, true);
            app.Thresh_DrawNewButton = uibutton(app.YBranchPlots, 'push');
            app.Thresh_DrawNewButton.ButtonPushedFcn = createCallbackFcn(app, @Thresh_DrawNewButtonPushed, true);
            app.Thresh_DrawNewButton.Text = 'Draw New';
            app.Thresh_SegmentButton = uibutton(app.YBranchPlots, 'push');
            app.Thresh_SegmentButton.ButtonPushedFcn = createCallbackFcn(app, @Thresh_SegmentButtonPushed, true);
            app.Thresh_SegmentButton.Text = 'Segment';
            app.ROIWeightpixelsrectsplineonlyLabel = uilabel(app.YBranchPlots);
            app.ROIWeightpixelsrectsplineonlyLabel.Text = {'ROI Weight (pixels)'; ''; ''; '(rect. & spline only)'};
            app.ROIWeightSpinner = uispinner(app.YBranchPlots);
            app.ROIWeightSpinner.Step = 0.1;
            app.ROIWeightSpinner.Limits = [0.1 Inf];
            app.ROIWeightSpinner.ValueChangedFcn = createCallbackFcn(app, @ROIWeightSpinnerValueChanged, true);
            app.ROIWeightSpinner.Value = 10;
            app.BranchSelectDropDown = uidropdown(app.YBranchPlots);
            app.BranchSelectDropDown.Items = {'Select Branch'};
            app.BranchSelectDropDown.ValueChangedFcn = createCallbackFcn(app, @BranchSelectDropDownValueChanged, true);
            app.BranchSelectDropDown.Value = 'Select Branch';
            app.Thresh_EditButton = uibutton(app.YBranchPlots, 'push');
            app.Thresh_EditButton.ButtonPushedFcn = createCallbackFcn(app, @Thresh_EditButtonPushed, true);
            app.Thresh_EditButton.Text = 'Edit';
            app.ROITypeSelectDropDown = uidropdown(app.YBranchPlots);
            app.ROITypeSelectDropDown.Items = {'Select Type', 'contour', 'rectangle', 'spline'};
            app.ROITypeSelectDropDown.ValueChangedFcn = createCallbackFcn(app, @ROITypeSelectDropDownValueChanged, true);
            app.ROITypeSelectDropDown.Value = 'Select Type';
            app.ROITypeDropDownLabel = uilabel(app.YBranchPlots);
            app.ROITypeDropDownLabel.Text = 'ROI Type';
            app.SplineSmoothnesscontoursplineonlyLabel = uilabel(app.YBranchPlots);
            app.SplineSmoothnesscontoursplineonlyLabel.Text = {'Spline Smoothness'; ''; ''; '(contour & spline only)'};
            app.SplineSmoothSpinner = uispinner(app.YBranchPlots);
            app.SplineSmoothSpinner.Step = 0.01;
            app.SplineSmoothSpinner.Limits = [0 1];
            app.SplineSmoothSpinner.ValueChangedFcn = createCallbackFcn(app, @SplineSmoothSpinnerValueChanged, true);
            app.SplineSmoothSpinner.Value = 0.2;
            app.ManualCentroidCheckBox = uicheckbox(app.YBranchPlots);
            app.ManualCentroidCheckBox.ValueChangedFcn = createCallbackFcn(app, @ManualCentroidCheckBoxValueChanged, true);
            app.ManualCentroidCheckBox.Text = 'Manual Centroid';
            app.TC_SyncROIsButton = uibutton(app.YBranchPlots, 'push');
            app.TC_SyncROIsButton.ButtonPushedFcn = createCallbackFcn(app, @TC_SyncROIsButtonPushed, true);
            app.TC_SyncROIsButton.Text = 'Sync ROIs';
            app.TC_SaveAsButton = uibutton(app.YBranchPlots, 'push');
            app.TC_SaveAsButton.ButtonPushedFcn = createCallbackFcn(app, @TC_SaveAsButtonPushed, true);
            app.TC_SaveAsButton.Text = 'Save Path';
            app.TC_ExitButton = uibutton(app.YBranchPlots, 'push');
            app.TC_ExitButton.ButtonPushedFcn = createCallbackFcn(app, @TC_ExitButtonPushed, true);
            app.TC_ExitButton.Text = 'Exit';
            app.TC_RefreshButton = uibutton(app.YBranchPlots, 'push');
            app.TC_RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @TC_RefreshButtonPushed, true);
            app.TC_RefreshButton.Text = 'Refresh GUI';
            app.Thresh_SetThresholdSpinner = uispinner(app.YBranchPlots);
            app.Thresh_SetThresholdSpinner.Step = 0.001;
            app.Thresh_SetThresholdSpinner.Limits = [0 1];
            app.Thresh_SetThresholdSpinner.ValueDisplayFormat = '%.3f';
            app.Thresh_SetThresholdSpinner.ValueChangedFcn = createCallbackFcn(app, @Thresh_SetThresholdSpinnerValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ybranchROI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end